classdef Calculo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        a                            matlab.ui.control.UIAxes
        DerivadaCheckBox             matlab.ui.control.CheckBox
        Funcin1EditFieldLabel        matlab.ui.control.Label
        Funcin1EditField             matlab.ui.control.EditField
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        IntegralCheckBox             matlab.ui.control.CheckBox
        X2EditFieldLabel             matlab.ui.control.Label
        X2EditField                  matlab.ui.control.NumericEditField
        ArcoCheckBox                 matlab.ui.control.CheckBox
        X1EditField_2Label           matlab.ui.control.Label
        X1EditField_2                matlab.ui.control.NumericEditField
        VolumenCheckBox              matlab.ui.control.CheckBox
        Funcin2EditFieldLabel        matlab.ui.control.Label
        Funcin2EditField             matlab.ui.control.EditField
        Funcin3EditFieldLabel        matlab.ui.control.Label
        Funcin3EditField             matlab.ui.control.EditField
        Mostrar1CheckBox             matlab.ui.control.CheckBox
        Mostrar2CheckBox             matlab.ui.control.CheckBox
        Mostrar3CheckBox             matlab.ui.control.CheckBox
        SuperficieCheckBox           matlab.ui.control.CheckBox
        EjesEditFieldLabel           matlab.ui.control.Label
        EjesEditField                matlab.ui.control.NumericEditField
        DiegoSnchezGonzlez2020Label  matlab.ui.control.Label
        ValordehEditFieldLabel       matlab.ui.control.Label
        ValordehEditField            matlab.ui.control.NumericEditField
        MostrarfuncinCheckBox        matlab.ui.control.CheckBox
        CEditFieldLabel              matlab.ui.control.Label
        CEditField                   matlab.ui.control.NumericEditField
        MostrarimplicitaCheckBox     matlab.ui.control.CheckBox
        ImplicitaEditFieldLabel      matlab.ui.control.Label
        ImplicitaEditField           matlab.ui.control.EditField
        CerosCheckBox                matlab.ui.control.CheckBox
        MaxMinCheckBox               matlab.ui.control.CheckBox
        InterseccinCheckBox          matlab.ui.control.CheckBox
        nderivadaKnobLabel           matlab.ui.control.Label
        nderivadaKnob                matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        d1 % display function 1
        d2 % display function 2
        d3 % display function 3
        d4 % display function 4
        sf1 % str function 1
        sf2 % str function 2
        sf3 % str function 3
        sf4 % str function 4
        der % display derivate
        x % x value 
        x1 % initial value for integrals
        x2 % initial value for integrals
        integrate % diaplay integral
        arc % display arc lenght
        volume % display volume
        surf % display surface area
        sep % axis separation
        vh % h value
        derf % Mostrar función derivada
        c % constante de integración
        ze % Encontrar ceros
        mm % Encontrar máximos y mínimos
        inters % Encontrar intersección entre curvas
        nd % número de derivada
    end
    
    methods (Access = private)
        
        function main (app)
            qf1 = strcat("@(x)",app.sf1);
            qf2 = strcat("@(x)",app.sf2);
            qf3 = strcat("@(x)",app.sf3);
            qf4 = strcat("@(x,y)",app.sf4);
       
            %EVALUAR
            if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==1),app.inters==0),app.der==0),app.integrate==0),app.arc==0),app.volume==0),app.surf==0)
                if and(and(app.d1 == 1,app.d2==0),app.d3==0)
                    hold(app.a,'off')
                    f1=str2func(qf1);
                    n=app.sep;
                    h=app.vh;
                    T=app.x-n:h:app.x+n;
                    F=f1(T);
                    plot(app.a,T,F,'b')
                    posy=f1(app.x);
                    xlim(app.a,[app.x-n app.x+n])
                    ylim(app.a,[posy-n posy+n])
                    grid(app.a,'on')
                    legend(app.a,'off')
                    title(app.a,sprintf('Función %s',app.sf1),"FontSize",14)
                    lgd = legend(app.a,sprintf('x = %d\ny = %d',app.x,posy),'Location','northwest');
                    title(lgd,sprintf('Valor de h = %d',h))
                    if app.ze == 1
                        hold (app.a,'on')
                        xdom=[];
                        j=1;
                        i=1;
                        jump=round(0.01/h);
                        if jump==0
                            jump=1;
                        end
                        while j <= length(F)
                            if and(F(j)>=-3*h,F(j)<=3*h)
                                xdom(i)=T(j);
                                i=i+1;
                                j=j+jump;
                            end
                            j=j+1;
                        end
                        for k=1:i-1
                            text(app.a,xdom(k),0,sprintf('%s Raiz = %d','\leftarrow',xdom(k)),'HorizontalAlignment','left','FontSize',10);
                        end
                        hold(app.a,'off')
                    end
                    hold(app.a,'off')
                elseif and(and(app.d2==1,app.d1==0),app.d3==0)
                    hold(app.a,'off')
                    f2=str2func(qf2);
                    n=app.sep;
                    h=app.vh;
                    T=app.x-n:h:app.x+n;
                    F=f2(T);
                    posy=f2(app.x);
                    plot(app.a,T,F,'b')
                    xlim(app.a,[app.x-n app.x+n])
                    ylim(app.a,[posy-n posy+n])
                    grid(app.a,'on')
                    legend(app.a,'off')
                    title(app.a,sprintf('Función %s',app.sf2),"FontSize",14)
                    lgd = legend(app.a,sprintf('x = %d\ny = %d',app.x,posy),'Location','northwest');
                    title(lgd,sprintf('Valor de h = %d',h))
                    if app.ze == 1
                        hold (app.a,'on')
                        xdom=[];
                        j=1;
                        i=1;
                        jump=round(0.01/h);
                        if jump==0
                            jump=1;
                        end
                        while j <= length(F)
                            if and(F(j)>=-3*h,F(j)<=3*h)
                                xdom(i)=T(j);
                                i=i+1;
                                j=j+jump;
                            end
                            j=j+1;
                        end
                        for k=1:i-1
                            text(app.a,xdom(k),0,sprintf('%s Raiz = %d','\leftarrow',xdom(k)),'HorizontalAlignment','left','FontSize',10);
                        end
                        hold(app.a,'off')
                    end
                    hold(app.a,'off')
                elseif and(and(app.d3==1,app.d1==0),app.d2==0)
                    hold(app.a,'off')
                    f3=str2func(qf3);
                    n=app.sep;
                    h=app.vh;
                    T=app.x-n:h:app.x+n;
                    F=f3(T);
                    posy=f3(app.x);
                    plot(app.a,T,F,'b')
                    xlim(app.a,[app.x-n app.x+n])
                    ylim(app.a,[posy-n posy+n])
                    grid(app.a,'on')
                    legend(app.a,'off')
                    title(app.a,sprintf('Función %s',app.sf3),"FontSize",14)
                    lgd = legend(app.a,sprintf('x = %d\ny = %d',app.x,posy),'Location','northwest');
                    title(lgd,sprintf('Valor de h = %d',h))
                    if app.ze == 1
                        hold (app.a,'on')
                        xdom=[];
                        j=1;
                        i=1;
                        jump=round(0.01/h);
                        if jump==0
                            jump=1;
                        end
                        while j <= length(F)
                            if and(F(j)>=-3*h,F(j)<=3*h)
                                xdom(i)=T(j);
                                i=i+1;
                                j=j+jump;
                            end
                            j=j+1;
                        end
                        for k=1:i-1
                            text(app.a,xdom(k),0,sprintf('%s Raiz = %d','\leftarrow',xdom(k)),'HorizontalAlignment','left','FontSize',10);
                        end
                        hold(app.a,'off')
                    end
                    hold(app.a,'off')
                elseif and(and(and(app.d4==1,app.d1==0),app.d2==0),app.d3==0)
                    hold(app.a,'off')
                    f4 = str2func(qf4);
                    n=app.sep;
                    h=app.vh;
                    fimplicit(app.a,f4,[app.x-n app.x+n -n n])
                    grid(app.a,'on')
                    title(app.a,'Función Implicita',"FontSize",14)
                    lgd = legend(app.a,sprintf('%s = 0',app.sf4),'Location','northwest');
                    title(lgd,sprintf('Valor de h = %d',h))
                    hold(app.a,'off')
                end
             end
             %INTERSECA
             if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==0),app.inters==1),app.der==0),app.integrate==0),app.arc==0),app.volume==0),app.surf==0)
                 if and(and(app.d1==1,app.d2==1),app.d3==0)
                     f1=str2func(qf1);
                     f2=str2func(qf2);
                     hold(app.a,'off')
                     legend(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     T=app.x-n:h:app.x+n;
                     F1=f1(T);
                     F2=f2(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if abs(F1(j)-F2(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     plot(app.a,T,F1,'b')
                     hold (app.a,'on')
                     plot(app.a,T,F2,'r')
                     for k=1:i
                         text(app.a,ix(k),iy(k),sprintf('%s Interseca = %d','\leftarrow',ix(k)),'HorizontalAlignment','left','FontSize',10);
                     end
                     mid=(f1(app.x)+f2(app.x))/2;
                     xlim(app.a,[app.x-n app.x+n])
                     ylim(app.a,[mid-n mid+n])
                     grid(app.a,'on')
                     title(app.a,sprintf('Intersección'),"FontSize",14)
                     legend(app.a,'on')
                     legend(app.a,sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_2 = %s',app.sf2),'Location','northwest');
                     hold (app.a,'off')
                 elseif and(and(app.d2==1,app.d3==1),app.d1==0)
                     f2=str2func(qf2);
                     f3=str2func(qf3);
                     hold(app.a,'off')
                     legend(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     T=app.x-n:h:app.x+n;
                     F2=f2(T);
                     F3=f3(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F2)
                         if abs(F2(j)-F3(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F2(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     plot(app.a,T,F2,'b')
                     hold (app.a,'on')
                     plot(app.a,T,F3,'r')
                     for k=1:i
                         text(app.a,ix(k),iy(k),sprintf('%s Interseca = %d','\leftarrow',ix(k)),'HorizontalAlignment','left','FontSize',10);
                     end
                     mid=(f2(app.x)+f3(app.x))/2;
                     xlim(app.a,[app.x-n app.x+n])
                     ylim(app.a,[mid-n mid+n])
                     grid(app.a,'on')
                     title(app.a,sprintf('Intersección'),"FontSize",14)
                     legend(app.a,'on')
                     legend(app.a,sprintf('f(x)_2 = %s',app.sf2),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     hold (app.a,'off')
                 elseif and(and(app.d1==1,app.d3==1),app.d2==0)
                     f1=str2func(qf1);
                     f3=str2func(qf3);
                     hold(app.a,'off')
                     legend(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     T=app.x-n:h:app.x+n;
                     F1=f1(T);
                     F3=f3(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if abs(F1(j)-F3(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     plot(app.a,T,F1,'b')
                     hold (app.a,'on')
                     plot(app.a,T,F3,'r')
                     for k=1:i
                         text(app.a,ix(k),iy(k),sprintf('%s Interseca = %d','\leftarrow',ix(k)),'HorizontalAlignment','left','FontSize',10);
                     end
                     mid=(f1(app.x)+f3(app.x))/2;
                     xlim(app.a,[app.x-n app.x+n])
                     ylim(app.a,[mid-n mid+n])
                     grid(app.a,'on')
                     title(app.a,sprintf('Intersección'),"FontSize",14)
                     legend(app.a,'on')
                     legend(app.a,sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     hold (app.a,'off')
                 elseif and(and(app.d1==1,app.d2==1),app.d3==1)
                     f1=str2func(qf1);
                     f2=str2func(qf2);
                     f3=str2func(qf3);
                     hold(app.a,'off')
                     legend(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     T=app.x-n:h:app.x+n;
                     F1=f1(T);
                     F2=f2(T);
                     F3=f3(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if or(abs(F1(j)-F2(j))<=10*h,abs(F1(j)-F3(j))<=10*h)
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         elseif abs(F2(j)-F3(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F2(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     plot(app.a,T,F1,'b')
                     hold (app.a,'on')
                     plot(app.a,T,F2,'r')
                     plot(app.a,T,F3,'g')
                     for k=1:i
                         text(app.a,ix(k),iy(k),sprintf('%s Interseca = %d','\leftarrow',ix(k)),'HorizontalAlignment','left','FontSize',10);
                     end
                     mid=(f1(app.x)+f2(app.x)+f3(app.x))/3;
                     xlim(app.a,[app.x-n app.x+n])
                     ylim(app.a,[mid-n mid+n])
                     grid(app.a,'on')
                     title(app.a,sprintf('Intersección'),"FontSize",14)
                     legend(app.a,'on')
                     legend(app.a,sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_2 = %s',app.sf2),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     hold (app.a,'off')
                 end
              end
              %DERIVA
              if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==0),app.inters==0),app.der==1),app.integrate==0),app.arc==0),app.volume==0),app.surf==0)
                  if and(and(app.d1==1,app.d2==0),app.d3==0)
                      hold (app.a,'off')
                      n=app.sep;
                      h=app.vh;
                      pp = app.nd;
                      T=app.x-n:h:app.x+n;
                      f1=str2func(qf1);
                      F=f1(T);
                      m = F;
                      for p = 1:pp
                          if p+1 == pp
                              bm = diff(m)/h;
                          end
                          m = diff(m)/h;
                      end
                      dm=length(m);
                      dr=round(dm/2);
                      if pp == 1
                          d=@(x) m(dr)*(x-app.x)+f1(app.x);
                      else
                          d=@(x) m(dr)*(x-app.x)+bm(dr);
                      end
                      plot(app.a,T,f1(T),'b')
                      grid(app.a,'on')
                      legend(app.a,'off')
                      title(app.a,sprintf('Derivada %s n = %d',app.sf1,pp),"FontSize",14)
                      xlim(app.a,[app.x-n app.x+n])
                      ylim(app.a,[f1(app.x)-n f1(app.x)+n])
                      hold (app.a,'on')
                      plot(app.a,T,d(T),'r')
                      lgd = legend(app.a,'Función original','Pendiente','Location','northwest');
                      title(lgd,sprintf('Pendiente = %d',m(dr)))
                      if app.derf==1
                          for p = 1: pp
                              T(end)=[];
                          end
                          if pp>1
                            bm(end)=[];
                            plot(app.a,T,bm,'c')
                            plot(app.a,T,m,'g')
                            lgd = legend(app.a,'Función original','Pendiente',sprintf('Derrivada %d',pp-1),sprintf('Derivada %d',pp),'Location','northwest');
                          else
                            plot(app.a,T,m,'g')
                            lgd = legend(app.a,'Función original','Pendiente','Derivada','Location','northwest');
                          end
                          title(lgd,sprintf('Pendiente = %d',m(dr)))
                      end
                      if app.mm == 1
                          xdomn=[];
                          ydomn=[];
                          xdomp=[];
                          ydomp=[];
                          xdomi=[];
                          ydomi=[];
                          j=1;
                          in=1;
                          ip=1;
                          ii=1;
                          jump=round(0.01/h);
                          if jump==0
                              jump=1;
                          end
                          m2 = diff(m)/h;
                          m2(end)=[];
                          while j <= dm
                              if and(m(j)>=-10*h,m(j)<=10*h)
                                  if m2(j)>0
                                      xdomn(in)=T(j);
                                      ydomn(in)=F(j);
                                      in=in+1;
                                      j=j+jump;
                                  elseif m2(j)<0
                                      xdomp(ip)=T(j);
                                      ydomp(ip)=F(j);
                                      ip=ip+1;
                                      j=j+jump;
                                  elseif abs(m(j-1)-m(j+1))<0.01*h
                                      xdomi(ii)=T(j);
                                      ydomi(ii)=F(j);
                                      ii=ii+1;
                                      j=j+jump;
                                  end
                              end
                              j=j+1;
                          end
                          for k=1:in-1
                              text(app.a,xdomn(k),ydomn(k),sprintf('%s\nMin = %d','\uparrow',xdomn(k)),'VerticalAlignment','top','FontSize',10);
                          end
                          for k=1:ip-1
                              text(app.a,xdomp(k),ydomp(k),sprintf('Max = %d\n%s',xdomp(k),'\downarrow'),'VerticalAlignment','bottom','FontSize',10);
                          end
                          for k=1:ii-1
                              text(app.a,xdomi(k),ydomi(k),sprintf('Inflexión = %d\n%s',xdomi(k),'\downarrow'),'VerticalAlignment','bottom','FontSize',10);
                          end
                      end
                      hold (app.a,'off')
                  end
                  
                  if and(and(app.d2 == 1,app.d1==0),app.d3==0)
                      hold (app.a,'off')
                      n=app.sep;
                      h=app.vh;
                      pp = app.nd;
                      T=app.x-n:h:app.x+n;
                      f2=str2func(qf2);
                      F=f2(T);
                      m = F;
                      for p = 1:pp
                          if p+1 == pp
                              bm = diff(m)/h;
                          end
                          m = diff(m)/h;
                      end
                      dm=length(m);
                      dr=round(dm/2);
                      if pp == 1
                          d=@(x) m(dr)*(x-app.x)+f2(app.x);
                      else
                          d=@(x) m(dr)*(x-app.x)+bm(dr);
                      end
                      plot(app.a,T,f2(T),'b') 
                      grid(app.a,'on')
                      legend(app.a,'off')
                      title(app.a,sprintf('Derivada %s n = %d',app.sf2,pp),"FontSize",14)
                      xlim(app.a,[app.x-n app.x+n])
                      ylim(app.a,[f2(app.x)-n f2(app.x)+n])
                      hold (app.a,'on')
                      plot(app.a,T,d(T),'r')
                      lgd = legend(app.a,'Función original','Pendiente','Location','northwest');
                      title(lgd,sprintf('Pendiente = %d',m(dr)))
                      if app.derf==1
                          for p = 1: pp
                              T(end)=[];
                          end
                          if pp>1
                            bm(end)=[];
                            plot(app.a,T,bm,'c')
                            plot(app.a,T,m,'g')
                            lgd = legend(app.a,'Función original','Pendiente',sprintf('Derrivada %d',pp-1),sprintf('Derivada %d',pp),'Location','northwest');
                          else
                            plot(app.a,T,m,'g')
                            lgd = legend(app.a,'Función original','Pendiente','Derivada','Location','northwest');
                          end
                          title(lgd,sprintf('Pendiente = %d',m(dr)))
                      end
                      if app.mm == 1
                          xdomn=[];
                          ydomn=[];
                          xdomp=[];
                          ydomp=[];
                          xdomi=[];
                          ydomi=[];
                          j=1;
                          in=1;
                          ip=1;
                          ii=1;
                          jump=round(0.01/h);
                          if jump==0
                              jump=1;
                          end
                          m2 = diff(m)/h;
                          m2(end)=[];
                          while j <= dm
                              if and(m(j)>=-10*h,m(j)<=10*h)
                                  if m2(j)>0
                                      xdomn(in)=T(j);
                                      ydomn(in)=F(j);
                                      in=in+1;
                                      j=j+jump;
                                  elseif m2(j)<0
                                      xdomp(ip)=T(j);
                                      ydomp(ip)=F(j);
                                      ip=ip+1;
                                      j=j+jump;
                                  elseif abs(m(j-1)-m(j+1))<0.01*h
                                      xdomi(ii)=T(j);
                                      ydomi(ii)=F(j);
                                      ii=ii+1;
                                      j=j+jump;
                                  end
                              end
                              j=j+1;
                          end
                          for k=1:in-1
                              text(app.a,xdomn(k),ydomn(k),sprintf('%s\nMin = %d','\uparrow',xdomn(k)),'VerticalAlignment','top','FontSize',10);
                          end
                          for k=1:ip-1
                              text(app.a,xdomp(k),ydomp(k),sprintf('Max = %d\n%s',xdomp(k),'\downarrow'),'VerticalAlignment','bottom','FontSize',10);
                          end
                          for k=1:ii-1
                              text(app.a,xdomi(k),ydomi(k),sprintf('Inflexión = %d\n%s',xdomi(k),'\downarrow'),'VerticalAlignment','bottom','FontSize',10);
                          end
                      end
                  end
                  if and(and(app.d3==1,app.d1==0),app.d2==0)
                      hold (app.a,'off')
                      n=app.sep;
                      h=app.vh;
                      pp = app.nd;
                      T=app.x-n:h:app.x+n;
                      f3=str2func(qf3);
                      F=f3(T);
                      m = F;
                      for p = 1:pp
                          if p+1 == pp
                              bm = diff(m)/h;
                          end
                          m = diff(m)/h;
                      end
                      dm=length(m);
                      dr=round(dm/2);
                      if pp == 1
                          d=@(x) m(dr)*(x-app.x)+f3(app.x);
                      else
                          d=@(x) m(dr)*(x-app.x)+bm(dr);
                      end
                      plot(app.a,T,f3(T),'b')
                      grid(app.a,'on')
                      legend(app.a,'off')
                      title(app.a,sprintf('Derivada %s n = %d',app.sf3,pp),"FontSize",14)
                      xlim(app.a,[app.x-n app.x+n])
                      ylim(app.a,[f3(app.x)-n f3(app.x)+n])
                      hold (app.a,'on')
                      plot(app.a,T,d(T),'r')
                      lgd = legend(app.a,'Función original','Pendiente','Location','northwest');
                      title(lgd,sprintf('Pendiente = %d',m(dr)))
                      if app.derf==1
                          for p = 1: pp
                              T(end)=[];
                          end
                          if pp>1
                            bm(end)=[];
                            plot(app.a,T,bm,'c')
                            plot(app.a,T,m,'g')
                            lgd = legend(app.a,'Función original','Pendiente',sprintf('Derrivada %d',pp-1),sprintf('Derivada %d',pp),'Location','northwest');
                          else
                            plot(app.a,T,m,'g')
                            lgd = legend(app.a,'Función original','Pendiente','Derivada','Location','northwest');
                          end
                          title(lgd,sprintf('Pendiente = %d',m(dr)))
                      end
                      if app.mm == 1
                          xdomn=[];
                          ydomn=[];
                          xdomp=[];
                          ydomp=[];
                          xdomi=[];
                          ydomi=[];
                          j=1;
                          in=1;
                          ip=1;
                          ii=1;
                          jump=round(0.01/h);
                          if jump==0
                              jump=1;
                          end
                          m2 = diff(m)/h;
                          m2(end)=[];
                          while j <= dm
                              if and(m(j)>=-10*h,m(j)<=10*h)
                                  if m2(j)>0
                                      xdomn(in)=T(j);
                                      ydomn(in)=F(j);
                                      in=in+1;
                                      j=j+jump;
                                  elseif m2(j)<0
                                      xdomp(ip)=T(j);
                                      ydomp(ip)=F(j);
                                      ip=ip+1;
                                      j=j+jump;
                                  elseif abs(m(j-1)-m(j+1))<0.01*h
                                      xdomi(ii)=T(j);
                                      ydomi(ii)=F(j);
                                      ii=ii+1;
                                      j=j+jump;
                                  end
                              end
                              j=j+1;
                          end
                          for k=1:in-1
                              text(app.a,xdomn(k),ydomn(k),sprintf('%s\nMin = %d','\uparrow',xdomn(k)),'VerticalAlignment','top','FontSize',10);
                          end
                          for k=1:ip-1
                              text(app.a,xdomp(k),ydomp(k),sprintf('Max = %d\n%s',xdomp(k),'\downarrow'),'VerticalAlignment','bottom','FontSize',10);
                          end
                          for k=1:ii-1
                              text(app.a,xdomi(k),ydomi(k),sprintf('Inflexión = %d\n%s',xdomi(k),'\downarrow'),'VerticalAlignment','bottom','FontSize',10);
                          end
                      end
                  end
             end
             %INTEGRA
             if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==0),app.inters==0),app.der==0),app.integrate==1),app.arc==0),app.volume==0),app.surf==0)
                 if and(and(app.d1==1,app.d2==0),app.d3==0)
                     hold (app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f1=str2func(qf1);
                     mid = (app.x1 + app.x2)/2;
                     T=app.x1:h:app.x2;
                     B=-n:h:n;
                     dif = app.x2 - app.x1;
                     valint=app.c;
                     k=0;
                     xval=app.x1;
                     z=round(dif/h);
                     vec=zeros(z,1);
                     i=1;
                     while k<=dif
                         valint=valint+h*(f1(xval));
                         vec(i)=valint;
                         i=i+1;
                         k=k+h;
                         xval=xval+h;
                     end
                     valint=valint-app.c;
                     iv=integral(f1,app.x1,app.x2);
                     area(app.a,T,f1(T))
                     hold (app.a,'on')
                     plot(app.a,B,f1(B))
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Integral %s',app.sf1),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f1(mid)-n f1(mid)+n])
                     lgd = legend(app.a,'Área','Función original','Location','northwest');
                     title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                     T=T';
                     if app.derf ==1
                         if length(vec)==length(T)
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         elseif length(vec)<length(T)
                             T(end)=[];
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         elseif length(vec)>length(T)
                             vec(end)=[];
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         end
                     end
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==0,app.d2==1),app.d3==0)
                     hold(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f2=str2func(qf2);
                     mid = (app.x1 + app.x2)/2;
                     T=app.x1:h:app.x2;
                     B=-n:h:n;
                     dif = app.x2 - app.x1;
                     valint=app.c;
                     k=0;
                     xval=app.x1;
                     z=round(dif/h);
                     vec=zeros(z,1);
                     i=1;
                     while k<=dif
                         valint=valint+h*(f2(xval));
                         vec(i)=valint;
                         i=i+1;
                         k=k+h;
                         xval=xval+h;
                     end
                     valint=valint-app.c;
                     iv=integral(f2,app.x1,app.x2);
                     area(app.a,T,f2(T))
                     hold (app.a,'on')
                     plot(app.a,B,f2(B))
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Integral %s',app.sf2),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f2(mid)-n f2(mid)+n])
                     lgd = legend(app.a,'Área','Función original','Location','northwest');
                     title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                     T=T';
                     if app.derf ==1
                         if length(vec)==length(T)
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         elseif length(vec)<length(T)
                             T(end)=[];
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         elseif length(vec)>length(T)
                             vec(end)=[];
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         end
                     end
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==0,app.d2==0),app.d3==1)
                     hold(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f3=str2func(qf3);
                     mid = (app.x1 + app.x2)/2;
                     T=app.x1:h:app.x2;
                     B=-n:h:n;
                     dif = app.x2 - app.x1;
                     valint=app.c;
                     k=0;
                     xval=app.x1;
                     z=round(dif/h);
                     vec=zeros(z,1);
                     i=1;
                     while k<=dif
                         valint=valint+h*(f3(xval));
                         vec(i)=valint;
                         i=i+1;
                         k=k+h;
                         xval=xval+h;
                     end
                     valint=valint-app.c;
                     iv=integral(f3,app.x1,app.x2);
                     area(app.a,T,f3(T))
                     hold (app.a,'on')
                     plot(app.a,B,f3(B))
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Integral %s',app.sf3),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f3(mid)-n f3(mid)+n])
                     lgd = legend(app.a,'Área','Función original','Location','northwest');
                     title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                     T=T';
                     if app.derf ==1
                         if length(vec)==length(T)
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         elseif length(vec)<length(T)
                             T(end)=[];
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         elseif length(vec)>length(T)
                             vec(end)=[];
                             plot(app.a,T,vec)
                             lgd = legend(app.a,'Área','Función original','Función antiderivada','Location','northwest');
                             title(lgd,sprintf('Área real = %d\nÁrea aprox = %d',iv,valint))
                         end
                     end
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==1,app.d2==1),app.d3==0)
                     hold(app.a,'off')
                     f1=str2func(qf1);
                     f2=str2func(qf2);
                     n=app.sep;
                     h=app.vh;
                     midx = (app.x1 + app.x2)/2;
                     midy = (f1(midx)+f2(midx))/2;
                     T=app.x1:h:app.x2;
                     Tc=-n:h:n;
                     F1=f1(T);
                     F2=f2(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if abs(F1(j)-F2(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     if ~isempty(ix)
                         if F1(1)==iy(1)
                             suma=0;
                         elseif F1(1)>F2(1)
                             suma=integral(f1,app.x1,ix(1))-integral(f2,app.x1,ix(1));
                         elseif F1(1)<F2(1)
                             suma=integral(f2,app.x1,ix(1))-integral(f1,app.x1,ix(1));
                         end
                         if length(ix)>1
                             for l=1:length(ix)-1
                                 if f1(ix(l)+0.001)<f2(ix(l)+0.001)
                                     suma=suma+(integral(f2,ix(l),ix(l+1))-integral(f1,ix(l),ix(l+1)));
                                 elseif f2(ix(l)+0.001)<f1(ix(l)+0.001)
                                     suma=suma+(integral(f1,ix(l),ix(l+1))-integral(f2,ix(l),ix(l+1)));
                                 end
                             end
                         end
                         if F1(end)~=iy(end)
                             if F1(end)<F2(end)
                                 suma=suma+integral(f2,ix(end),app.x2)-integral(f1,ix(end),app.x2);
                             elseif F1(end)>F2(end)
                                 suma=suma+integral(f1,ix(end),app.x2)-integral(f2,ix(end),app.x2);
                             end
                         end
                     else
                         if F1(1)<F2(1)
                             suma=integral(f2,app.x1,app.x2)-integral(f1,app.x1,app.x2);
                         elseif F1(1)>F2(1)
                             suma=integral(f1,app.x1,app.x2)-integral(f2,app.x1,app.x2);
                         end
                     end
                     be = [T, fliplr(T)];
                     inBetween = [F1, fliplr(F2)];
                     fill(app.a,be,inBetween,'g')
                     hold (app.a,'on')
                     plot(app.a,Tc,f1(Tc))
                     plot(app.a,Tc,f2(Tc))
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Área entre curvas'),"FontSize",14)
                     xlim(app.a,[midx-n midx+n])
                     ylim(app.a,[midy-n midy+n])
                     lgd = legend(app.a,'Área',sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_2 = %s',app.sf2),'Location','northwest');
                     title(lgd,sprintf('Área = %d',suma))
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==1,app.d2==0),app.d3==1)
                     hold(app.a,'off')
                     f1=str2func(qf1);
                     f3=str2func(qf3);
                     n=app.sep;
                     h=app.vh;
                     midx = (app.x1 + app.x2)/2;
                     midy = (f1(midx)+f3(midx))/2;
                     T=app.x1:h:app.x2;
                     Tc=-n:h:n;
                     F1=f1(T);
                     F3=f3(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if abs(F1(j)-F3(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     if ~isempty(ix)
                         if F1(1)==iy(1)
                             suma=0;
                         elseif F1(1)<F3(1)
                             suma=integral(f3,app.x1,ix(1))-integral(f1,app.x1,ix(1));
                         elseif F1(1)>F3(1)
                             suma=integral(f1,app.x1,ix(1))-integral(f3,app.x1,ix(1));
                         end
                         if length(ix)>1
                             for l=1:length(ix)-1
                                 if f1(ix(l)+0.001)<f3(ix(l)+0.001)
                                     suma=suma+(integral(f3,ix(l),ix(l+1))-integral(f1,ix(l),ix(l+1)));
                                 elseif f3(ix(l)+0.001)<f1(ix(l)+0.001)
                                     suma=suma+(integral(f1,ix(l),ix(l+1))-integral(f3,ix(l),ix(l+1)));
                                 end
                             end
                         end
                         if F1(end)~=iy(end)
                             if F1(end)<F3(end)
                                 suma=suma+integral(f3,ix(end),app.x2)-integral(f1,ix(end),app.x2);
                             elseif F1(end)>F3(end)
                                 suma=suma+integral(f1,ix(end),app.x2)-integral(f3,ix(end),app.x2);
                             end
                         end
                     else
                         if F1(1)<F3(1)
                             suma=integral(f3,app.x1,app.x2)-integral(f1,app.x1,app.x2);
                         elseif F1(1)>F3(1)
                             suma=integral(f1,app.x1,app.x2)-integral(f3,app.x1,app.x2);
                         end
                     end
                     be = [T, fliplr(T)];
                     inBetween = [F1, fliplr(F3)];
                     fill(app.a,be,inBetween,'g')
                     hold (app.a,'on')
                     plot(app.a,Tc,f1(Tc))
                     plot(app.a,Tc,f3(Tc))
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Área entre curvas'),"FontSize",14)
                     xlim(app.a,[midx-n midx+n])
                     ylim(app.a,[midy-n midy+n])
                     lgd = legend(app.a,'Área',sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     title(lgd,sprintf('Área = %d',suma))
                     hold (app.a,'off')
                 end
                 
                 if and(and(app.d1==0,app.d2==1),app.d3==1)
                     hold(app.a,'off')
                     f3=str2func(qf3);
                     f2=str2func(qf2);
                     n=app.sep;
                     h=app.vh;
                     midx = (app.x1 + app.x2)/2;
                     midy = (f3(midx)+f2(midx))/2;
                     T=app.x1:h:app.x2;
                     Tc=-n:h:n;
                     F3=f3(T);
                     F2=f2(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F3)
                         if abs(F3(j)-F2(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F3(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     if ~isempty(ix)
                         suma=0;
                         if F3(1)==iy(1)
                             suma=0;
                         elseif F3(1)<F2(1)
                             suma=integral(f2,app.x1,ix(1))-integral(f3,app.x1,ix(1));
                         elseif F3(1)>F2(1)
                             suma=integral(f3,app.x1,ix(1))-integral(f2,app.x1,ix(1));
                         end
                         if length(ix)>1
                             for l=1:length(ix)-1
                                 if f3(ix(l)+0.001)<f2(ix(l)+0.001)
                                     suma=suma+(integral(f2,ix(l),ix(l+1))-integral(f3,ix(l),ix(l+1)));
                                 elseif f2(ix(l)+0.001)<f3(ix(l)+0.001)
                                     suma=suma+(integral(f3,ix(l),ix(l+1))-integral(f2,ix(l),ix(l+1)));
                                 end
                             end
                         end
                         if F3(end)~=iy(end)
                             if F3(end)<F2(end)
                                 suma=suma+integral(f2,ix(end),app.x2)-integral(f3,ix(end),app.x2);
                             elseif F3(end)>F2(end)
                                 suma=suma+integral(f3,ix(end),app.x2)-integral(f2,ix(end),app.x2);
                             end
                         end
                     else
                         if F3(1)<F2(1)
                             suma=integral(f2,app.x1,app.x2)-integral(f3,app.x1,app.x2);
                         elseif F3(1)>F2(1)
                             suma=integral(f3,app.x1,app.x2)-integral(f2,app.x1,app.x2);
                         end
                     end
                     be = [T, fliplr(T)];
                     inBetween = [F3, fliplr(F2)];
                     fill(app.a,be,inBetween,'g')
                     hold (app.a,'on')
                     plot(app.a,Tc,f2(Tc))
                     plot(app.a,Tc,f3(Tc))
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Área entre curvas'),"FontSize",14)
                     xlim(app.a,[midx-n midx+n])
                     ylim(app.a,[midy-n midy+n])
                     lgd = legend(app.a,'Área',sprintf('f(x)_2 = %s',app.sf2),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     title(lgd,sprintf('Área = %d',suma))
                     hold (app.a,'off')
                 end
              end
              %ARCO
              if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==0),app.inters==0),app.der==0),app.integrate==0),app.arc==1),app.volume==0),app.surf==0)
                  if and(and(app.d1==1,app.d2==0),app.d3==0)
                      hold (app.a,'off')
                      n=app.sep;
                      h=app.vh;
                      f1=str2func(qf1);
                      mid = (app.x1 + app.x2)/2;
                      dif = app.x2 - app.x1;
                      arco=0;
                      k=0;
                      xval=app.x1;
                      while k<=dif
                          arco=arco+sqrt((h^2)+(f1(xval+h)-f1(xval))^2);
                          k=k+h;
                          xval=xval+h;
                      end
                      T=app.x1:h:app.x2;
                      B=-n:h:n;
                      F=f1(T);
                      G=f1(B);
                      plot(app.a,B,G,'b')
                      grid(app.a,'on')
                      hold (app.a,'on')
                      plot(app.a,T,F,'r')
                      legend(app.a,'off')
                      title(app.a,sprintf('Longitud de arco %s',app.sf1),"FontSize",14)
                      xlim(app.a,[mid-n mid+n])
                      ylim(app.a,[f1(mid)-n f1(mid)+n])
                      lgd = legend(app.a,'Arco no incluido','Arco incluido','Location','northwest');
                      title(lgd,sprintf('Longitud de arco = %d',arco))
                      hold (app.a,'off')
                  end
                  if and(and(app.d2==1,app.d1==0),app.d3==0)
                      hold(app.a,'off')
                      n=app.sep;
                      h=app.vh;
                      f2=str2func(qf2);
                      mid = (app.x1 + app.x2)/2;
                      dif = app.x2 - app.x1;
                      arco=0;
                      k=0;
                      xval=app.x1;
                      while k<=dif
                          arco=arco+sqrt((h^2)+(f2(xval+h)-f2(xval))^2);
                          k=k+h;
                          xval=xval+h;
                      end
                      T=app.x1:h:app.x2;
                      B=-n:h:n;
                      F=f2(T);
                      G=f2(B);
                      plot(app.a,B,G,'b')
                      grid(app.a,'on')
                      hold (app.a,'on')
                      plot(app.a,T,F,'r')
                      legend(app.a,'off')
                      title(app.a,sprintf('Longitud de arco %s',app.sf2),"FontSize",14)
                      xlim(app.a,[mid-n mid+n])
                      ylim(app.a,[f2(mid)-n f2(mid)+n])
                      lgd = legend(app.a,'Arco no incluido','Arco incluido','Location','northwest');
                      title(lgd,sprintf('Longitud de arco = %d',arco))
                      hold (app.a,'off')
                  end
                  if and(and(app.d3==1,app.d1==0),app.d2==0)
                      hold(app.a,'off')
                      n=app.sep;
                      h=app.vh;
                      f3=str2func(qf3);
                      mid = (app.x1 + app.x2)/2;
                      dif = app.x2 - app.x1;
                      arco=0;
                      k=0;
                      xval=app.x1;
                      while k<=dif
                          arco=arco+sqrt((h^2)+(f3(xval+h)-f3(xval))^2);
                          k=k+h;
                          xval=xval+h;
                      end
                      T=app.x1:h:app.x2;
                      B=-n:h:n;
                      F=f3(T);
                      G=f3(B);
                      plot(app.a,B,G,'b')
                      grid(app.a,'on')
                      hold (app.a,'on')
                      plot(app.a,T,F,'r')
                      legend(app.a,'off')
                      title(app.a,sprintf('Longitud de arco %s',app.sf3),"FontSize",14)
                      xlim(app.a,[mid-n mid+n])
                      ylim(app.a,[f3(mid)-n f3(mid)+n])
                      lgd = legend(app.a,'Arco no incluido','Arco incluido','Location','northwest');
                      title(lgd,sprintf('Longitud de arco = %d',arco))
                      hold (app.a,'off')
                  end
             end
             %VOLUMEN
             if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==0),app.inters==0),app.der==0),app.integrate==0),app.arc==0),app.volume==1),app.surf==0)
                 if and(and(app.d1==1,app.d2==0),app.d3==0)
                     hold (app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f1=str2func(qf1);
                     mid = (app.x1 + app.x2)/2;
                     dif = app.x2 - app.x1;
                     vol = 0;
                     k = 0;
                     xval = app.x1;
                     while k<=dif
                         vol = vol + h*pi*(f1(xval))^2;
                         k=k+h;
                         xval=xval+h;
                     end
                     T=app.x1:h:app.x2;
                     F=f1(T);
                     G=-f1(T);
                     B=-n:h:n;
                     H=f1(B);
                     J=-f1(B);
                     area(app.a,T,F)
                     hold (app.a,'on')
                     area(app.a,T,G)
                     plot(app.a,B,H,'k')
                     plot(app.a,B,J,'k')
                     legend(app.a,'off')
                     title(app.a,sprintf('Volumen de revolución %s',app.sf1),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f1(mid)-n f1(mid)+n])
                     lgd = legend(app.a,sprintf('x1 = %d \nx2 = %d',app.x1,app.x2),'Location','northwest');
                     title(lgd,sprintf('Volumen = %d',vol))
                     hold (app.a,'off')
                 end
                 if and(and(app.d2==1,app.d1==0),app.d3==0)
                     hold(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f2=str2func(qf2);
                     mid = (app.x1 + app.x2)/2;
                     dif = app.x2 - app.x1;
                     vol = 0;
                     k = 0;
                     xval = app.x1;
                     while k<=dif
                         vol = vol + h*pi*(f2(xval))^2;
                         k=k+h;
                         xval=xval+h;
                     end
                     T=app.x1:h:app.x2;
                     F=f2(T);
                     G=-f2(T);
                     B=-n:h:n;
                     H=f2(B);
                     J=-f2(B);
                     area(app.a,T,F)
                     hold (app.a,'on')
                     area(app.a,T,G)
                     plot(app.a,B,H,'k')
                     plot(app.a,B,J,'k')
                     legend(app.a,'off')
                     title(app.a,sprintf('Volumen de revolución %s',app.sf2),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f2(mid)-n f2(mid)+n])
                     lgd = legend(app.a,sprintf('x1 = %d \nx2 = %d',app.x1,app.x2),'Location','northwest');
                     title(lgd,sprintf('Volumen = %d',vol))
                     hold (app.a,'off')
                 end
                 if and(and(app.d3==1,app.d1==0),app.d2==0)
                     hold(app.a,'off')
                     h=app.vh;
                     n=app.sep;
                     f3=str2func(qf3);
                     mid = (app.x1 + app.x2)/2;
                     dif = app.x2 - app.x1;
                     vol = 0;
                     k = 0;
                     xval = app.x1;
                     while k<=dif
                         vol = vol + h*pi*(f3(xval))^2;
                         k=k+h;
                         xval=xval+h;
                     end
                     T=app.x1:h:app.x2;
                     F=f3(T);
                     G=-f3(T);
                     B=-n:h:n;
                     H=f3(B);
                     J=-f3(B);
                     area(app.a,T,F)
                     hold (app.a,'on')
                     area(app.a,T,G)
                     plot(app.a,B,H,'k')
                     plot(app.a,B,J,'k')
                     legend(app.a,'off')
                     title(app.a,sprintf('Volumen de revolución %s',app.sf3),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f3(mid)-n f3(mid)+n])
                     lgd = legend(app.a,sprintf('x1 = %d \nx2 = %d',app.x1,app.x2),'Location','northwest');
                     title(lgd,sprintf('Volumen = %d',vol))
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==1,app.d2==1),app.d3==0)
                     hold(app.a,'off')
                     f1=str2func(qf1);
                     f2=str2func(qf2);
                     n=app.sep;
                     h=app.vh;
                     midx = (app.x1 + app.x2)/2;
                     midy = (f1(midx)+f2(midx))/2;
                     T=app.x1:h:app.x2;
                     Tc=-n:h:n;
                     F1=f1(T);
                     F2=f2(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if abs(F1(j)-F2(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     if ~isempty(ix)
                         if F1(1)==iy(1)
                             vol=0;
                         elseif abs(F1(1))>abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f2(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                         elseif abs(F1(1))<abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f1(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                         elseif abs(F1(1))==abs(F2(1))
                             if abs(f1(app.x1+0.001))<abs(f2(app.x1+0.001))
                                vol = 0;
                                k = 0;
                                xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f1(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                             else 
                                 vol = 0;
                                 k = 0;
                                 xval = app.x1;
                                 while k<=ix(1) - app.x1
                                    vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f2(xval))^2));
                                    k=k+h;
                                    xval=xval+h;
                                 end
                             end
                         end
                         if length(ix)>1
                             for l=1:length(ix)-1
                                 if abs(f1(ix(l)+0.001))<abs(f2(ix(l)+0.001))
                                     k = 0;
                                     xval = ix(l);
                                     while k<=ix(l+1) - ix(l)
                                         vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f1(xval))^2));
                                         k=k+h;
                                         xval=xval+h;
                                     end
                                  elseif abs(f2(ix(l)+0.001))<abs(f1(ix(l)+0.001))
                                     k = 0;
                                     xval = ix(l);
                                     while k<=ix(l+1) - ix(l)
                                         vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f2(xval))^2));
                                         k=k+h;
                                         xval=xval+h;
                                     end
                                 end
                             end
                         end
                         if F1(end)~=iy(end)
                             if abs(F1(end))<abs(F2(end))
                                 k = 0;
                                 xval = ix(end);
                                 while k<= app.x2 - ix(end)
                                     vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f1(xval))^2));
                                     k=k+h;
                                     xval=xval+h;
                                 end
                             elseif abs(F1(end))>abs(F2(end))
                                 k = 0;
                                 xval = ix(end);
                                 while k<= app.x2 - ix(end)
                                     vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f2(xval))^2));
                                     k=k+h;
                                     xval=xval+h;
                                 end
                             end
                         end
                     else
                         if abs(F1(1))<abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<= app.x2 - app.x1
                                 vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f1(xval))^2));
                                 k=k+h;
                                 xval=xval+h;
                             end
                         elseif abs(F1(1))>abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<= app.x2 - app.x1
                                 vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f2(xval))^2));
                                 k=k+h;
                                 xval=xval+h;
                             end
                         end
                     end
                     be = [T, fliplr(T)];
                     inBetween1 = [F1, fliplr(F2)];
                     inBetween2 = [-F2, fliplr(-F1)];
                     fill(app.a,be,inBetween1,'g')
                     hold (app.a,'on')
                     plot(app.a,Tc,f1(Tc))
                     plot(app.a,Tc,f2(Tc))
                     plot(app.a,Tc,-f1(Tc))
                     plot(app.a,Tc,-f2(Tc))
                     fill(app.a,be,inBetween2,'g')
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Volumen entre curvas'),"FontSize",14)
                     xlim(app.a,[midx-n midx+n])
                     ylim(app.a,[midy-n midy+n])
                     lgd = legend(app.a,'Volumen',sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_2 = %s',app.sf2),'Location','northwest');
                     title(lgd,sprintf('Volumen = %d',vol))
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==1,app.d2==0),app.d3==1)
                     hold(app.a,'off')
                     f1=str2func(qf1);
                     f3=str2func(qf3);
                     n=app.sep;
                     h=app.vh;
                     midx = (app.x1 + app.x2)/2;
                     midy = (f1(midx)+f3(midx))/2;
                     T=app.x1:h:app.x2;
                     Tc=-n:h:n;
                     F1=f1(T);
                     F3=f3(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F1)
                         if abs(F1(j)-F3(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F1(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     if ~isempty(ix)
                         if F1(1)==iy(1)
                             vol=0;
                         elseif abs(F1(1))>abs(F3(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f3(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                         elseif abs(F1(1))<abs(F3(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f1(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                         elseif abs(F1(1))==abs(F3(1))
                             if abs(f1(app.x1+0.001))<abs(f3(app.x1+0.001))
                                vol = 0;
                                k = 0;
                                xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f1(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                             else 
                                 vol = 0;
                                 k = 0;
                                 xval = app.x1;
                                 while k<=ix(1) - app.x1
                                    vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f3(xval))^2));
                                    k=k+h;
                                    xval=xval+h;
                                 end
                             end
                         end
                         if length(ix)>1
                             for l=1:length(ix)-1
                                 if abs(f1(ix(l)+0.001))<abs(f3(ix(l)+0.001))
                                     k = 0;
                                     xval = ix(l);
                                     while k<=ix(l+1) - ix(l)
                                         vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f1(xval))^2));
                                         k=k+h;
                                         xval=xval+h;
                                     end
                                 elseif abs(f3(ix(l)+0.001))<abs(f1(ix(l)+0.001))
                                     k = 0;
                                     xval = ix(l);
                                     while k<=ix(l+1) - ix(l)
                                         vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f3(xval))^2));
                                         k=k+h;
                                         xval=xval+h;
                                     end
                                 end
                             end
                         end
                         if F1(end)~=iy(end)
                             if abs(F1(end))<abs(F3(end))
                                 k = 0;
                                 xval = ix(end);
                                 while k<= app.x2 - ix(end)
                                     vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f1(xval))^2));
                                     k=k+h;
                                     xval=xval+h;
                                 end
                             elseif abs(F1(end))>abs(F3(end))
                                 k = 0;
                                 xval = ix(end);
                                 while k<= app.x2 - ix(end)
                                     vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f3(xval))^2));
                                     k=k+h;
                                     xval=xval+h;
                                 end
                             end
                         end
                     else
                         if abs(F1(1))<abs(F3(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<= app.x2 - app.x1
                                 vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f1(xval))^2));
                                 k=k+h;
                                 xval=xval+h;
                             end
                         elseif abs(F1(1))>abs(F3(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<= app.x2 - app.x1
                                 vol = vol + ((h*pi*(f1(xval))^2)-(h*pi*(f3(xval))^2));
                                 k=k+h;
                                 xval=xval+h;
                             end
                         end
                     end
                     be = [T, fliplr(T)];
                     inBetween1 = [F1, fliplr(F3)];
                     inBetween2 = [-F3, fliplr(-F1)];
                     fill(app.a,be,inBetween1,'g')
                     hold (app.a,'on')
                     plot(app.a,Tc,f1(Tc))
                     plot(app.a,Tc,f3(Tc))
                     plot(app.a,Tc,-f1(Tc))
                     plot(app.a,Tc,-f3(Tc))
                     fill(app.a,be,inBetween2,'g')
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Volumen entre curvas'),"FontSize",14)
                     xlim(app.a,[midx-n midx+n])
                     ylim(app.a,[midy-n midy+n])
                     lgd = legend(app.a,'Volumen',sprintf('f(x)_1 = %s',app.sf1),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     title(lgd,sprintf('Volumen = %d',vol))
                     hold (app.a,'off')
                 end
                 if and(and(app.d1==0,app.d2==1),app.d3==1)
                     hold(app.a,'off')
                     f3=str2func(qf3);
                     f2=str2func(qf2);
                     n=app.sep;
                     h=app.vh;
                     midx = (app.x1 + app.x2)/2;
                     midy = (f3(midx)+f2(midx))/2;
                     T=app.x1:h:app.x2;
                     Tc=-n:h:n;
                     F3=f3(T);
                     F2=f2(T);
                     ix=[];
                     iy=[];
                     j=1;
                     jump=round(0.01/h);
                     if jump==0
                         jump=1;
                     end
                     i=0;
                     while j<=length(F3)
                         if abs(F3(j)-F2(j))<=10*h
                             i=i+1;
                             ix(i)=T(j);
                             iy(i)=F3(j);
                             j=j+jump;
                         end
                         j=j+1;
                     end
                     if ~isempty(ix)
                         if F3(1)==iy(1)
                             vol=0;
                         elseif abs(F3(1))>abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f2(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                         elseif abs(F3(1))<abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f3(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                         elseif abs(F3(1))==abs(F2(1))
                             if abs(f3(app.x1+0.001))<abs(f2(app.x1+0.001))
                                vol = 0;
                                k = 0;
                                xval = app.x1;
                             while k<=ix(1) - app.x1
                                  vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f3(xval))^2));
                                  k=k+h;
                                  xval=xval+h;
                             end
                             else 
                                 vol = 0;
                                 k = 0;
                                 xval = app.x1;
                                 while k<=ix(1) - app.x1
                                    vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f2(xval))^2));
                                    k=k+h;
                                    xval=xval+h;
                                 end
                             end
                         end
                         if length(ix)>1
                             for l=1:length(ix)-1
                                 if abs(f3(ix(l)+0.001))<abs(f2(ix(l)+0.001))
                                     k = 0;
                                     xval = ix(l);
                                     while k<=ix(l+1) - ix(l)
                                         vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f3(xval))^2));
                                         k=k+h;
                                         xval=xval+h;
                                     end
                                 elseif abs(f2(ix(l)+0.001))<abs(f3(ix(l)+0.001))
                                     k = 0;
                                     xval = ix(l);
                                     while k<=ix(l+1) - ix(l)
                                         vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f2(xval))^2));
                                         k=k+h;
                                         xval=xval+h;
                                     end
                                 end
                             end
                         end
                         if F3(end)~=iy(end)
                             if abs(F3(end))<abs(F2(end))
                                 k = 0;
                                 xval = ix(end);
                                 while k<= app.x2 - ix(end)
                                     vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f3(xval))^2));
                                     k=k+h;
                                     xval=xval+h;
                                 end
                             elseif abs(F3(end))>abs(F2(end))
                                 k = 0;
                                 xval = ix(end);
                                 while k<= app.x2 - ix(end)
                                     vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f2(xval))^2));
                                     k=k+h;
                                     xval=xval+h;
                                 end
                             end
                         end
                     else
                         if abs(F3(1))<abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<= app.x2 - app.x1
                                 vol = vol + ((h*pi*(f2(xval))^2)-(h*pi*(f3(xval))^2));
                                 k=k+h;
                                 xval=xval+h;
                             end
                         elseif abs(F3(1))>abs(F2(1))
                             vol = 0;
                             k = 0;
                             xval = app.x1;
                             while k<= app.x2 - app.x1
                                 vol = vol + ((h*pi*(f3(xval))^2)-(h*pi*(f2(xval))^2));
                                 k=k+h;
                                 xval=xval+h;
                             end
                         end
                     end
                     be = [T, fliplr(T)];
                     inBetween1 = [F3, fliplr(F2)];
                     inBetween2 = [-F2, fliplr(-F3)];
                     fill(app.a,be,inBetween1,'g')
                     hold (app.a,'on')
                     plot(app.a,Tc,f3(Tc))
                     plot(app.a,Tc,f2(Tc))
                     plot(app.a,Tc,-f3(Tc))
                     plot(app.a,Tc,-f2(Tc))
                     fill(app.a,be,inBetween2,'g')
                     grid(app.a,'on')
                     legend(app.a,'off')
                     title(app.a,sprintf('Volumen entre curvas'),"FontSize",14)
                     xlim(app.a,[midx-n midx+n])
                     ylim(app.a,[midy-n midy+n])
                     lgd = legend(app.a,'Volumen',sprintf('f(x)_2 = %s',app.sf2),sprintf('f(x)_3 = %s',app.sf3),'Location','northwest');
                     title(lgd,sprintf('Volumen = %d',vol))
                     hold (app.a,'off')
                 end
             end
             %SUPERFICIE
             if and(and(and(and(and(and(or(or(or(app.d1==1,app.d2==1),app.d3==1),app.d4==0),app.inters==0),app.der==0),app.integrate==0),app.arc==0),app.volume==0),app.surf==1)
                 if and(and(app.d1==1,app.d2==0),app.d3==0)
                     hold (app.a,'off')
                     f1=str2func(qf1);
                     n=app.sep;
                     h=app.vh;
                     mid = (app.x1 + app.x2)/2;
                     dif = app.x2 - app.x1;
                     s=0;
                     k=0;
                     xval=app.x1;
                     while k<=dif
                         s=s+sqrt((h^2)+(f1(xval+h)-f1(xval))^2)*f1(xval)*2*pi;
                         k=k+h;
                         xval=xval+h;
                     end
                     T=app.x1:h:app.x2;
                     F=f1(T);
                     G=-f1(T);
                     B=-n:h:n;
                     H=f1(B);
                     J=-f1(B);
                     area(app.a,T,F)
                     hold (app.a,'on')
                     area(app.a,T,G)
                     plot(app.a,B,H,'k')
                     plot(app.a,B,J,'k')
                     legend(app.a,'off')
                     title(app.a,sprintf('Área de superficie %s',app.sf1),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f1(mid)-n f1(mid)+n])
                     lgd = legend(app.a,sprintf('x1 = %d \nx2 = %d',app.x1,app.x2),'Location','northwest');
                     title(lgd,sprintf('Surf área = %d',s))
                     hold (app.a,'off')
                 end
                 if and(and(app.d2==1,app.d1==0),app.d3==0)
                     hold(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f2=str2func(qf2);
                     mid = (app.x1 + app.x2)/2;
                     dif = app.x2 - app.x1;
                     s=0;
                     k=0;
                     xval=app.x1;
                     while k<=dif
                         s=s+sqrt((h^2)+(f2(xval+h)-f2(xval))^2)*f2(xval)*2*pi;
                         k=k+h;
                         xval=xval+h;
                     end
                     T=app.x1:h:app.x2;
                     F=f2(T);
                     G=-f2(T);
                     B=-n:h:n;
                     H=f2(B);
                     J=-f2(B);
                     area(app.a,T,F)
                     hold (app.a,'on')
                     area(app.a,T,G)
                     plot(app.a,B,H,'k')
                     plot(app.a,B,J,'k')
                     legend(app.a,'off')
                     title(app.a,sprintf('Área de superficie %s',app.sf2),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f2(mid)-n f2(mid)+n])
                     lgd = legend(app.a,sprintf('x1 = %d \nx2 = %d',app.x1,app.x2),'Location','northwest');
                     title(lgd,sprintf('Surf área = %d',s))
                     hold (app.a,'off')
                 end
                 if and(and(app.d3==1,app.d1==0),app.d2==0)
                     hold(app.a,'off')
                     n=app.sep;
                     h=app.vh;
                     f3=str2func(qf3);
                     mid = (app.x1 + app.x2)/2;
                     dif = app.x2 - app.x1;
                     s=0;
                     k=0;
                     xval=app.x1;
                     while k<=dif
                         s=s+sqrt((h^2)+(f3(xval+h)-f3(xval))^2)*f3(xval)*2*pi;
                         k=k+h;
                         xval=xval+h;
                     end
                     T=app.x1:h:app.x2;
                     F=f3(T);
                     G=-f3(T);
                     B=-n:h:n;
                     H=f3(B);
                     J=-f3(B);
                     area(app.a,T,F)
                     hold (app.a,'on')
                     area(app.a,T,G)
                     plot(app.a,B,H,'k')
                     plot(app.a,B,J,'k')
                     legend(app.a,'off')
                     title(app.a,sprintf('Área de superficie %s',app.sf3),"FontSize",14)
                     xlim(app.a,[mid-n mid+n])
                     ylim(app.a,[f3(mid)-n f3(mid)+n])
                     lgd = legend(app.a,sprintf('x1 = %d \nx2 = %d',app.x1,app.x2),'Location','northwest');
                     title(lgd,sprintf('Surf área = %d',s))
                     hold (app.a,'off')
                 end
             end
        end
    end                      

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.d1 = 0;
            app.d2 = 0;
            app.d3 = 0;
            app.d4 = 0;
            app.sf1 = "";
            app.sf2 = "";
            app.sf3 = "";
            app.sf4 = "";
            app.der = 0;
            app.x = 0;
            app.x1 = 0;
            app.x2 = 0;
            app.integrate = 0;
            app.arc = 0;
            app.volume = 0;
            app.surf = 0;
            app.sep = 1;
            app.vh = 0.001;
            app.derf = 0;
            app.c = 0;
            app.ze = 0;
            app.mm = 0;
            app.inters = 0;
            app.nd = 1;
        end

        % Value changed function: Funcin1EditField
        function Funcin1EditFieldValueChanged(app, event)
            app.sf1 = app.Funcin1EditField.Value;           
        end

        % Value changed function: Funcin2EditField
        function Funcin2EditFieldValueChanged(app, event)
            app.sf2 = app.Funcin2EditField.Value;
        end

        % Value changed function: Funcin3EditField
        function Funcin3EditFieldValueChanged(app, event)
            app.sf3 = app.Funcin3EditField.Value;         
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.x = app.XEditField.Value;
        end

        % Value changed function: X1EditField_2
        function X1EditField_2ValueChanged(app, event)
            app.x1 = app.X1EditField_2.Value;           
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            app.x2 = app.X2EditField.Value;           
        end

        % Value changed function: DerivadaCheckBox
        function DerivadaCheckBoxValueChanged(app, event)
            app.der = app.DerivadaCheckBox.Value;  
            main(app)
        end

        % Value changed function: IntegralCheckBox
        function IntegralCheckBoxValueChanged(app, event)
            app.integrate = app.IntegralCheckBox.Value;  
            main(app)
        end

        % Value changed function: ArcoCheckBox
        function ArcoCheckBoxValueChanged(app, event)
            app.arc = app.ArcoCheckBox.Value;      
            main(app)
        end

        % Value changed function: VolumenCheckBox
        function VolumenCheckBoxValueChanged(app, event)
            app.volume = app.VolumenCheckBox.Value;  
            main(app)
        end

        % Value changed function: Mostrar1CheckBox
        function Mostrar1CheckBoxValueChanged(app, event)
            app.d1 = app.Mostrar1CheckBox.Value;            
            main(app)
        end

        % Value changed function: Mostrar2CheckBox
        function Mostrar2CheckBoxValueChanged(app, event)
            app.d2 = app.Mostrar2CheckBox.Value;            
            main(app)
        end

        % Value changed function: Mostrar3CheckBox
        function Mostrar3CheckBoxValueChanged(app, event)
            app.d3 = app.Mostrar3CheckBox.Value;       
            main(app)
        end

        % Value changed function: SuperficieCheckBox
        function SuperficieCheckBoxValueChanged(app, event)
            app.surf = app.SuperficieCheckBox.Value;
            main(app)
        end

        % Value changed function: EjesEditField
        function EjesEditFieldValueChanged(app, event)
            app.sep = app.EjesEditField.Value;
        end

        % Value changed function: ValordehEditField
        function ValordehEditFieldValueChanged(app, event)
            app.vh = app.ValordehEditField.Value;
        end

        % Value changed function: MostrarfuncinCheckBox
        function MostrarfuncinCheckBoxValueChanged(app, event)
            app.derf = app.MostrarfuncinCheckBox.Value;
            main(app)
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            app.c = app.CEditField.Value;           
        end

        % Value changed function: MostrarimplicitaCheckBox
        function MostrarimplicitaCheckBoxValueChanged(app, event)
            app.d4 = app.MostrarimplicitaCheckBox.Value;
            main(app)
        end

        % Value changed function: ImplicitaEditField
        function ImplicitaEditFieldValueChanged(app, event)
            app.sf4 = app.ImplicitaEditField.Value;           
        end

        % Value changed function: CerosCheckBox
        function CerosCheckBoxValueChanged(app, event)
            app.ze = app.CerosCheckBox.Value;
            main(app)
        end

        % Value changed function: MaxMinCheckBox
        function MaxMinCheckBoxValueChanged(app, event)
            app.mm = app.MaxMinCheckBox.Value;
            main(app)
        end

        % Value changed function: InterseccinCheckBox
        function InterseccinCheckBoxValueChanged(app, event)
            app.inters = app.InterseccinCheckBox.Value;
            main(app)
        end

        % Value changed function: nderivadaKnob
        function nderivadaKnobValueChanged(app, event)
            app.nd = str2double(app.nderivadaKnob.Value);
            main(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Cálculo')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DerivadaCheckBox = uicheckbox(app.UIFigure);
            app.DerivadaCheckBox.ValueChangedFcn = createCallbackFcn(app, @DerivadaCheckBoxValueChanged, true);
            app.DerivadaCheckBox.Text = 'Derivada';
            app.Funcin1EditFieldLabel = uilabel(app.UIFigure);
            app.Funcin1EditFieldLabel.Text = 'Función 1';
            app.Funcin1EditField = uieditfield(app.UIFigure, 'text');
            app.Funcin1EditField.ValueChangedFcn = createCallbackFcn(app, @Funcin1EditFieldValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.IntegralCheckBox = uicheckbox(app.UIFigure);
            app.IntegralCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegralCheckBoxValueChanged, true);
            app.IntegralCheckBox.Text = 'Integral';
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.ArcoCheckBox = uicheckbox(app.UIFigure);
            app.ArcoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArcoCheckBoxValueChanged, true);
            app.ArcoCheckBox.Text = 'Arco';
            app.X1EditField_2Label = uilabel(app.UIFigure);
            app.X1EditField_2Label.Text = 'X1';
            app.X1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField_2.ValueChangedFcn = createCallbackFcn(app, @X1EditField_2ValueChanged, true);
            app.VolumenCheckBox = uicheckbox(app.UIFigure);
            app.VolumenCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolumenCheckBoxValueChanged, true);
            app.VolumenCheckBox.Text = 'Volumen';
            app.Funcin2EditFieldLabel = uilabel(app.UIFigure);
            app.Funcin2EditFieldLabel.Text = 'Función 2';
            app.Funcin2EditField = uieditfield(app.UIFigure, 'text');
            app.Funcin2EditField.ValueChangedFcn = createCallbackFcn(app, @Funcin2EditFieldValueChanged, true);
            app.Funcin3EditFieldLabel = uilabel(app.UIFigure);
            app.Funcin3EditFieldLabel.Text = 'Función 3';
            app.Funcin3EditField = uieditfield(app.UIFigure, 'text');
            app.Funcin3EditField.ValueChangedFcn = createCallbackFcn(app, @Funcin3EditFieldValueChanged, true);
            app.Mostrar1CheckBox = uicheckbox(app.UIFigure);
            app.Mostrar1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Mostrar1CheckBoxValueChanged, true);
            app.Mostrar1CheckBox.Text = 'Mostrar 1';
            app.Mostrar2CheckBox = uicheckbox(app.UIFigure);
            app.Mostrar2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Mostrar2CheckBoxValueChanged, true);
            app.Mostrar2CheckBox.Text = 'Mostrar 2';
            app.Mostrar3CheckBox = uicheckbox(app.UIFigure);
            app.Mostrar3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Mostrar3CheckBoxValueChanged, true);
            app.Mostrar3CheckBox.Text = 'Mostrar 3';
            app.SuperficieCheckBox = uicheckbox(app.UIFigure);
            app.SuperficieCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuperficieCheckBoxValueChanged, true);
            app.SuperficieCheckBox.Text = 'Superficie';
            app.EjesEditFieldLabel = uilabel(app.UIFigure);
            app.EjesEditFieldLabel.Text = 'Ejes';
            app.EjesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EjesEditField.Limits = [0.0001 Inf];
            app.EjesEditField.ValueChangedFcn = createCallbackFcn(app, @EjesEditFieldValueChanged, true);
            app.EjesEditField.Value = 1;
            app.DiegoSnchezGonzlez2020Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2020Label.Text = 'Diego Sánchez González - 2020';
            app.ValordehEditFieldLabel = uilabel(app.UIFigure);
            app.ValordehEditFieldLabel.Text = 'Valor de h';
            app.ValordehEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValordehEditField.Limits = [1e-06 1];
            app.ValordehEditField.ValueChangedFcn = createCallbackFcn(app, @ValordehEditFieldValueChanged, true);
            app.ValordehEditField.Value = 0.001;
            app.MostrarfuncinCheckBox = uicheckbox(app.UIFigure);
            app.MostrarfuncinCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarfuncinCheckBoxValueChanged, true);
            app.MostrarfuncinCheckBox.Text = 'Mostrar función';
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.MostrarimplicitaCheckBox = uicheckbox(app.UIFigure);
            app.MostrarimplicitaCheckBox.ValueChangedFcn = createCallbackFcn(app, @MostrarimplicitaCheckBoxValueChanged, true);
            app.MostrarimplicitaCheckBox.Text = 'Mostrar implicita';
            app.ImplicitaEditFieldLabel = uilabel(app.UIFigure);
            app.ImplicitaEditFieldLabel.Text = 'Implicita';
            app.ImplicitaEditField = uieditfield(app.UIFigure, 'text');
            app.ImplicitaEditField.ValueChangedFcn = createCallbackFcn(app, @ImplicitaEditFieldValueChanged, true);
            app.CerosCheckBox = uicheckbox(app.UIFigure);
            app.CerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CerosCheckBoxValueChanged, true);
            app.CerosCheckBox.Text = 'Ceros';
            app.MaxMinCheckBox = uicheckbox(app.UIFigure);
            app.MaxMinCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxMinCheckBoxValueChanged, true);
            app.MaxMinCheckBox.Text = 'Max&Min';
            app.InterseccinCheckBox = uicheckbox(app.UIFigure);
            app.InterseccinCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterseccinCheckBoxValueChanged, true);
            app.InterseccinCheckBox.Text = 'Intersección';
            app.nderivadaKnobLabel = uilabel(app.UIFigure);
            app.nderivadaKnobLabel.Text = 'n-derivada';
            app.nderivadaKnob = uiknob(app.UIFigure, 'discrete');
            app.nderivadaKnob.Items = {'1', '2', '3', '4'};
            app.nderivadaKnob.ValueChangedFcn = createCallbackFcn(app, @nderivadaKnobValueChanged, true);
            app.nderivadaKnob.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end