classdef Gato < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        a                            matlab.ui.control.UIAxes
        DiegoSnchezGonzlez2021Label  matlab.ui.control.Label
        PlayButton                   matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        BlanckspaceLabel             matlab.ui.control.Label
        CircleLabel                  matlab.ui.control.Label
        CrossLabel                   matlab.ui.control.Label
        NextmovementLabel            matlab.ui.control.Label
        CircleCheckBox               matlab.ui.control.CheckBox
        CrossCheckBox                matlab.ui.control.CheckBox
        TimeEditFieldLabel           matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        play % activate the programm
        box1 % box 1 input
        box2 % box 2 input
        box3 % box 3 imput
        box4 % box 4 input
        box5 % box 5 input
        box6 % box 6 input
        box7 % box 7 input
        box8 % box 8 input
        box9 % box 9 input
        circle % Next movement is circle
        cross % Next movement is cross
        time % Timelapses
    end
    
    methods (Access = private)
        
        function fvorschauer(app)
            hold(app.a,'off')
            again = 1; pass = true; zufall = 0; alone = 0; erste = 1;
            game = zeros(3); iscircle = 0; iscross = 0;
            game(1,1) = app.box1; game(1,2) = app.box2; game(1,3) = app.box3;
            game(2,1) = app.box4; game(2,2) = app.box5; game(2,3) = app.box6;
            game(3,1) = app.box7; game(3,2) = app.box8; game(3,3) = app.box9;
            for i = 1:3
                for j = 1:3
                    if and(and(game(i,j)~=-1,game(i,j)~=0),game(i,j)~=1)
                        pass = false;
                    end
                    if game(i,j) == 0
                        alone = alone+1;
                    end
                end
            end
            if alone == 9
                alone = true;
            else 
                alone = false;
            end
            if and(app.circle==1,app.cross==1)
                pass = false;
            elseif and(app.circle==0,app.cross==0)
                zufall = 1;
            end
            d = linspace(0,3);
            f1 = @(x) 0.0001*x-1;
            f2 = @(x) 0.0001*x-2;
            f3 = @(x) 10000*(x-1);
            f4 = @(x) 10000*(x-2);
            plot(app.a,d,f1(d),'k',d,f2(d),'k',d,f3(d),'k',d,f4(d),'k','MarkerSize',15)
            xlim(app.a,[0 3])
            ylim(app.a,[-3 0])
            xticks(app.a,[0 3]) 
            xticklabels(app.a,{'0','1'});
            yticks(app.a,[-3 0])
            yticklabels(app.a,{'0','1'});
            hold(app.a,'on')
            while and(again > 0,pass == true)
                fin = 0;
                for i = 1:3
                    for j = 1:3
                        if game(j,i) == 1
                            b = i-0.5; c = -j+0.5; h = 0.3;
                            f = @(x,y) (x-b).^2 + (y-c).^2 - h^2;
                            fimplicit(app.a,f,[b-h b+h c-h c+h],'-k','MarkerSize',15)
                            title(app.a,sprintf('Round %d',again))
                            hold(app.a,'on')
                        elseif game(j,i) == -1
                            b = i-0.5; c = -j+0.5; h = 0.3;
                            f = @(x,y) (x-b).^2 - (y-c).^2 - 0.0001^2;
                            fimplicit(app.a,f,[b-h b+h c-h c+h],'-k','MarkerSize',15)
                            title(app.a,sprintf('Round %d',again))
                            hold(app.a,'on')
                        end
                        if game(i,j) ~= 0
                           fin = fin+1;
                        end
                    end
                end
                if fin == 9
                    break
                end
                ocoords = []; xcoords = [];
                for t = 1:2
                    for i = 1:3
                        add = 0;
                        for j =1:3
                            if t == 1 % Horizontal
                               add = add+game(i,j); 
                            elseif t == 2 % Vertical
                               add = add+game(j,i);
                            end
                        end
                        if or(add==3,add==-3)
                           again = 0;
                        elseif or(add==2,add==-2)
                           if t == 1
                               for g = 1:3
                                   if and(game(i,g) == 0,add==2)
                                       ocoords(end+1) = i; ocoords(end+1) = g;
                                   elseif and(game(i,g) == 0,add==-2)
                                       xcoords(end+1) = i; xcoords(end+1) = g;
                                   end
                               end                               
                           elseif t == 2
                               for g = 1:3
                                   if and(game(g,i) == 0,add==2)
                                       ocoords(end+1) = g; ocoords(end+1) = i;
                                   elseif and(game(g,i) == 0,add==-2)
                                       xcoords(end+1) = g; xcoords(end+1) = i;
                                   end
                               end
                           end
                        end
                    end
                    add = 0;
                    for k = 1:3
                        if t == 1
                            add = add+game(k,k);
                        elseif t == 2
                            add = add+game(4-k,k);
                        end
                    end
                    if or(add==3,add==-3)
                        again = 0;
                    elseif or(add==2,add==-2)
                        if t == 1
                            for g = 1:3
                                if and(game(g,g) == 0,add==2)
                                    ocoords(end+1) = g; ocoords(end+1) = g;
                                elseif and(game(g,g) == 0,add==-2)
                                    xcoords(end+1) = g; xcoords(end+1) = g;
                                end
                            end
                        elseif t == 2
                            for g = 1:3
                                if and(game(4-g,g) == 0,add==2)
                                    ocoords(end+1) = 4-g; ocoords(end+1) = g;
                                elseif and(game(4-g,g) == 0,add==-2)
                                    xcoords(end+1) = 4-g; xcoords(end+1) = g;
                                end
                            end
                        end
                    end
                end
                if and(isempty(ocoords),isempty(xcoords))
                    iszero = false;
                    while iszero == false
                        n = rand; m = rand; 
                        [n,m] = gato9(n,m);
                        if game(n,m) == 0
                            iszero = true;
                            if zufall == 0
                                whal = -1;
                                if and(again == 1, app.circle == 1)
                                    iscircle = 1; app.circle = 0;
                                elseif and(again == 1,app.cross == 1)
                                    iscross = 1; app.cross = 0;
                                end
                            else
                                if erste == 1
                                    whal = rand; erste = 0;
                                else
                                    whal = -1;
                                end
                            end
                                if or(or(and(whal < 0.5,whal~=-1), iscircle == 1),and(whal == -1,iscircle == 1))
                                    iscross = 1; iscircle = 0;
                                    game(n,m) = 1;
                                elseif or(or(and(whal >= 0.5,whal~=-1), iscross == 1),and(whal ==-1,iscross ==1))
                                    iscircle = 1; iscross = 0;
                                    game(n,m) = -1;
                                end
                         end
                    end
                else 
                    if zufall == 0
                        whal = -1;
                       if and(again == 1, app.circle == 1)
                            iscircle = 1; app.circle = 0;
                        elseif and(again == 1,app.cross == 1)
                            iscross = 1; app.cross = 0;
                       end
                    else 
                            if erste == 1
                                whal = rand; erste = 0;
                            else 
                                whal = -1;
                            end
                    end
                        if or(or(and(whal < 0.5,whal~=-1), iscircle == 1),and(whal == -1,iscircle == 1))
                            iscross = 1; iscircle = 0;
                            if ~isempty(ocoords)
                                len = length(ocoords)/2;
                                l = 2*gato10(len)-1;
                                game(ocoords(l),ocoords(l+1)) = 1;
                            elseif ~isempty(xcoords)
                                len = length(xcoords)/2;
                                l = 2*gato10(len)-1;
                                game(xcoords(l),xcoords(l+1)) = 1;
                            end
                        elseif or(or(and(whal >= 0.5,whal~=-1), iscross == 1),and(whal ==-1,iscross ==1))
                            iscircle = 1; iscross = 0;
                            if ~isempty(xcoords)
                                len = length(xcoords)/2;
                                l = 2*gato10(len)-1;
                                game(xcoords(l),xcoords(l+1)) = -1;
                            elseif ~isempty(ocoords)
                                len = length(ocoords)/2;
                                l = 2*gato10(len)-1;
                                game(ocoords(l),ocoords(l+1)) = -1;
                            end
                       end
                end
                if again ~= 0
                    again = again+1;
                end
                if alone == true
                    alone = false;
                    again = 1;
                end
                pause(app.time)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.play = 0;
            app.box1 = 0;
            app.box2 = 0;
            app.box3 = 0;
            app.box4 = 0;
            app.box5 = 0;
            app.box6 = 0;
            app.box7 = 0;
            app.box8 = 0;
            app.box9 = 0;
            app.circle = 0;
            app.cross = 0;
            app.time = 1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            fvorschauer(app)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.box1 = app.EditField.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.box2 = app.EditField_2.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.box3 = app.EditField_3.Value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.box4 = app.EditField_4.Value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.box5 = app.EditField_5.Value;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.box6 = app.EditField_6.Value;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.box7 = app.EditField_7.Value;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.box8 = app.EditField_8.Value;
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.box9 = app.EditField_9.Value;
        end

        % Value changed function: CircleCheckBox
        function CircleCheckBoxValueChanged(app, event)
            app.circle = app.CircleCheckBox.Value;
        end

        % Value changed function: CrossCheckBox
        function CrossCheckBoxValueChanged(app, event)
            app.cross = app.CrossCheckBox.Value;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.time = app.TimeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Vorschauer')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DiegoSnchezGonzlez2021Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2021Label.Text = 'Diego Sánchez González - 2021';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.BlanckspaceLabel = uilabel(app.UIFigure);
            app.BlanckspaceLabel.Text = '0 = Blanckspace';
            app.CircleLabel = uilabel(app.UIFigure);
            app.CircleLabel.Text = '1 = Circle';
            app.CrossLabel = uilabel(app.UIFigure);
            app.CrossLabel.Text = '-1 = Cross';
            app.NextmovementLabel = uilabel(app.UIFigure);
            app.NextmovementLabel.Text = 'Next movement';
            app.CircleCheckBox = uicheckbox(app.UIFigure);
            app.CircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @CircleCheckBoxValueChanged, true);
            app.CircleCheckBox.Text = 'Circle';
            app.CrossCheckBox = uicheckbox(app.UIFigure);
            app.CrossCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossCheckBoxValueChanged, true);
            app.CrossCheckBox.Text = 'Cross';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Limits = [1 Inf];
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gato

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end