classdef Drawings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HoldonCheckBox               matlab.ui.control.CheckBox
        SelectdataButtonGroup        matlab.ui.container.ButtonGroup
        MexicoButton                 matlab.ui.control.RadioButton
        RickButton                   matlab.ui.control.RadioButton
        DiegoButton                  matlab.ui.control.RadioButton
        PencilCheckBox               matlab.ui.control.CheckBox
        ColorsCheckBox               matlab.ui.control.CheckBox
        PointsKnob                   matlab.ui.control.Knob
        PointsKnobLabel              matlab.ui.control.Label
        VectorsKnob                  matlab.ui.control.Knob
        VectorsKnobLabel             matlab.ui.control.Label
        OriginalCheckBox             matlab.ui.control.CheckBox
        WaitinginputLabel            matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        StopCheckBox                 matlab.ui.control.CheckBox
        FileNameEditField            matlab.ui.control.EditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        EnterdataCheckBox            matlab.ui.control.CheckBox
        StartButton                  matlab.ui.control.Button
        DiegoSnchezGonzlez2021Label  matlab.ui.control.Label
        a                            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        start % Button to start the program
        stop % Button to stop the program
        enter % Button to enter self-made data
        file % To call the data
        vec % To specify the number of vectors (presicion)
        p % To specify the number of points
        ori % To plot original points
        col % To choose colors
        pen % To create the pencil
        sig % To create the star
        rick % To create my signature
        mex % To create mexico
        ho % To hold the figure
    end
    
    methods (Access = private)
        
        function [f] = everything(app)
            if app.stop == 0
                if app.ho == 0
                    hold (app.a,"off")
                    plot(app.a,1,1)
                end
                hold (app.a,'on')
                app.Lamp.Color = 'r';
                app.WaitinginputLabel.Text = 'Still working';
                % Código que llama a los pixeles
                if app.enter == 1
                    A = importdata(app.file);
                    title(app.a,'Fourier Discrete Transform')
                else
                    if app.sig == 1
                        A = importdata('sigdiego_f.txt');
                        title(app.a,'Diego')
                    elseif app.rick == 1
                        A = importdata('rick_f.txt');
                        title(app.a,'Rick')
                    elseif app.mex == 1
                        A = importdata('mex_f.txt');
                        title(app.a,'Mexico')
                    end
                end
                m = max(abs(min(A(:,1))-max(A(:,1))),abs(min(A(:,2))-max(A(:,2))))/2;
                xlim(app.a,[min(A(:,1))+abs((min(A(:,1))-max(A(:,1)))/2)-m min(A(:,1))+abs((min(A(:,1))-max(A(:,1)))/2)+m])
                ylim(app.a,[min(A(:,2))+abs((min(A(:,2))-max(A(:,2)))/2)-m min(A(:,2))+abs((min(A(:,2))-max(A(:,2)))/2)+m])
                B = [];
                % Código que transforma las coordenadas a complejos y los
                % grafica
                for k = 1:size(A,1)
                    B = cat(1,B,A(k,1)+A(k,2)*1i);
                    if app.ori == 1
                        plot(app.a,A(k,1),A(k,2),'ok','MarkerEdgeColor',"none",'MarkerFaceColor','k','MarkerSize',5)
                    end
                end
                % Código que crea la funcion
                C = [];
                f = '@(t)';
                k = 0;
                for n = -round(app.vec/2):round(app.vec/2)
                    if app.stop == 1
                        break
                    end
                    D = [];
                    k = k+1;
                    s = strcat('exp(',num2str(n),'*2*pi*1i*t)'); % ¿Un menos antes de n?
                    for t = 0:1/size(B,1):1
                        D = cat(1,D,exp(-n*2*pi*1i*t)); % ¿Un menos antes de la n?
                    end
                    D(end) = [];
                    C = cat(1,C,dot(B,D)/size(B,1)); % Se calcula cada coeficiente complejo
                    f = strcat(f,'(',num2str(C(k)),')*',s,'+');
                end
                % código que crea el dibujo
                if app.stop == 0
                    E = [];
                    k = 0;
                    c = 0;
                    f = f(1:end-1);
                    f = str2func(f);
                    for t = 0:1/app.p:1
                        k = k+1;
                        c = c+1;
                        if app.stop == 1
                            break
                        end
                        if app.pen == 1
                            M = [];
                            for n = -round(app.vec/2):round(app.vec/2)
                                M = cat(1,M,exp(n*2*pi*1i*t));
                            end
                            N = M.*C;
                            Z = 0;
                            u = 0;
                            y = 1;
                            for n = 0:round(app.vec/2)-1
                                if n > 0
                                    u = u+1;
                                    for l = 1:2
                                        if l == 1
                                            y = y+1;
                                            Z = cat(1,Z,Z(y-1)+N(round(app.vec/2)+u));
                                        else
                                            y = y+1;
                                            Z = cat(1,Z,Z(y-1)+N(round(app.vec/2)-u));
                                        end
                                    end
                                else
                                    y = y+1;
                                    Z = cat(1,Z,N(round(app.vec/2)));
                                end
                            end
                            for l = 1:size(Z,1)
                                Z(l) = Z(l)-2*imag(Z(l))*1i;
                            end
                        end
                        E = cat(1,E,[real(f(t)) -imag(f(t))]);
                        if t > 0
                            if app.col == 1
                                if c > 20
                                    c = 1;
                                end
                                colors = color(c);
                            else
                                colors = [0 0 0];
                            end
                            plot(app.a,[E(k-1,1) E(k,1)],[E(k-1,2) E(k,2)],'Color',colors)
                            if app.pen == 1
                                for j = 2:size(Z,1)
                                    plot(app.a,[real(Z(j-1)) real(Z(j))],[imag(Z(j-1)) imag(Z(j))],'Color',[0 0 0])
                                end
                            end
                            pause(0.1)
                        end
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.Lamp.Color = 'g';
                app.WaitinginputLabel.Text = 'Program finished';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 1;
            app.stop = 0;
            app.enter = 0;
            app.file = '';
            app.vec = 100;
            app.p = 100;
            app.ori = 0;
            app.col = 0;
            app.pen = 0;
            app.sig = 1;
            app.rick = 0;
            app.mex = 0;
            app.ho = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            everything(app)
        end

        % Value changed function: EnterdataCheckBox
        function EnterdataCheckBoxValueChanged(app, event)
            app.enter = app.EnterdataCheckBox.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file = app.FileNameEditField.Value;
        end

        % Value changed function: StopCheckBox
        function StopCheckBoxValueChanged(app, event)
            app.stop = app.StopCheckBox.Value;
        end

        % Value changed function: OriginalCheckBox
        function OriginalCheckBoxValueChanged(app, event)
            app.ori = app.OriginalCheckBox.Value;
        end

        % Value changed function: VectorsKnob
        function VectorsKnobValueChanged(app, event)
            app.vec = round(app.VectorsKnob.Value);
        end

        % Value changed function: PointsKnob
        function PointsKnobValueChanged(app, event)
            app.p = round(app.PointsKnob.Value);
        end

        % Value changed function: ColorsCheckBox
        function ColorsCheckBoxValueChanged(app, event)
            app.col = app.ColorsCheckBox.Value;
        end

        % Value changed function: PencilCheckBox
        function PencilCheckBoxValueChanged(app, event)
            app.pen = app.PencilCheckBox.Value;
        end

        % Selection changed function: SelectdataButtonGroup
        function SelectdataButtonGroupSelectionChanged(app, event)
            app.sig = app.DiegoButton.Value;
            app.rick = app.RickButton.Value;
            app.mex = app.MexicoButton.Value;
        end

        % Value changed function: HoldonCheckBox
        function HoldonCheckBoxValueChanged(app, event)
            app.ho = app.HoldonCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Discrete Fourier Transform')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DiegoSnchezGonzlez2021Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2021Label.Text = 'Diego Sánchez González 2021';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EnterdataCheckBox = uicheckbox(app.UIFigure);
            app.EnterdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnterdataCheckBoxValueChanged, true);
            app.EnterdataCheckBox.Text = 'Enter data';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.StopCheckBox = uicheckbox(app.UIFigure);
            app.StopCheckBox.ValueChangedFcn = createCallbackFcn(app, @StopCheckBoxValueChanged, true);
            app.StopCheckBox.Text = 'Stop';
            app.Lamp = uilamp(app.UIFigure);
            app.WaitinginputLabel = uilabel(app.UIFigure);
            app.WaitinginputLabel.Text = 'Waiting input';
            app.OriginalCheckBox = uicheckbox(app.UIFigure);
            app.OriginalCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginalCheckBoxValueChanged, true);
            app.OriginalCheckBox.Text = 'Original';
            app.VectorsKnobLabel = uilabel(app.UIFigure);
            app.VectorsKnobLabel.Text = 'Vectors';
            app.VectorsKnob = uiknob(app.UIFigure, 'continuous');
            app.VectorsKnob.Limits = [1 500];
            app.VectorsKnob.ValueChangedFcn = createCallbackFcn(app, @VectorsKnobValueChanged, true);
            app.VectorsKnob.Value = 100;
            app.PointsKnobLabel = uilabel(app.UIFigure);
            app.PointsKnobLabel.Text = 'Points';
            app.PointsKnob = uiknob(app.UIFigure, 'continuous');
            app.PointsKnob.Limits = [1 500];
            app.PointsKnob.ValueChangedFcn = createCallbackFcn(app, @PointsKnobValueChanged, true);
            app.PointsKnob.Value = 100;
            app.ColorsCheckBox = uicheckbox(app.UIFigure);
            app.ColorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorsCheckBoxValueChanged, true);
            app.ColorsCheckBox.Text = 'Colors';
            app.PencilCheckBox = uicheckbox(app.UIFigure);
            app.PencilCheckBox.ValueChangedFcn = createCallbackFcn(app, @PencilCheckBoxValueChanged, true);
            app.PencilCheckBox.Text = 'Pencil';
            app.SelectdataButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectdataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectdataButtonGroupSelectionChanged, true);
            app.SelectdataButtonGroup.Title = 'Select data';
            app.DiegoButton = uiradiobutton(app.SelectdataButtonGroup);
            app.DiegoButton.Text = 'Diego';
            app.DiegoButton.Value = true;
            app.RickButton = uiradiobutton(app.SelectdataButtonGroup);
            app.RickButton.Text = 'Rick';
            app.MexicoButton = uiradiobutton(app.SelectdataButtonGroup);
            app.MexicoButton.Text = 'Mexico';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldonCheckBoxValueChanged, true);
            app.HoldonCheckBox.Text = 'Hold on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drawings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end