classdef lebenspiel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        a                          matlab.ui.control.UIAxes
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        FileNameEditFieldLabel     matlab.ui.control.Label
        FileNameEditField          matlab.ui.control.EditField
        EjesEditFieldLabel         matlab.ui.control.Label
        EjesEditField              matlab.ui.control.NumericEditField
        IntervaloEditFieldLabel    matlab.ui.control.Label
        IntervaloEditField         matlab.ui.control.NumericEditField
        TamaoCuboEditFieldLabel    matlab.ui.control.Label
        TamaoCuboEditField         matlab.ui.control.NumericEditField
        ColorCheckBox              matlab.ui.control.CheckBox
        PauseCheckBox              matlab.ui.control.CheckBox
        RandomCheckBox             matlab.ui.control.CheckBox
        SemillaEditFieldLabel      matlab.ui.control.Label
        SemillaEditField           matlab.ui.control.NumericEditField
        StartButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        n % Iteraciones que realizará el programa
        file % Nombre del archivo
        start % Start Button
        ejes % Tamaño de los ejes
        inter % Intervalo
        cubo % Tamaño del cubo
        p % stop button
        zei % zeichnung button
        r % Random
        sem % Semilla random
    end
    
    methods (Access = private)
        
        function spiel(app)
              
size = app.ejes;
m1 = zeros(size);
m2 = zeros(size);
dummy = zeros(size);
hold (app.a,'off')

if app.r == 1
    m1 = rand(size);
    m1(:,1) = 0; m1(:,size) = 0; m1(1,:) = 0; m1(size,:) = 0;
    for x = 2:size-1
        for y = 2:size-1
            if m1(x,y)<= app.sem/100
                m1(x,y) = 1;
                if app.zei == 1
                    c = rand;
                    c = round(20*c);
                    c = color(c);
                    plot(app.a,x,y,'s','MarkerSize',app.cubo,'MarkerFaceColor',c)
                else
                    plot(app.a,x,y,'sk','MarkerSize',app.cubo,'MarkerFaceColor','k')
                end
                xlim(app.a,[0 size])
                ylim(app.a,[0 size])
                xticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
                xticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
                yticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
                yticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
                title(app.a,'Lebenspiel 0')
                hold (app.a,'on')
            else
                m1(x,y) = 0;
            end
        end
    end
else
    data = importdata(app.file);
    for j = 1:length(data(:,1))
        m1(data(j,2),data(j,1)) = 1;
        if app.zei == 1
            c = rand;
            c = round(20*c);
            c = color(c);
            plot(app.a,data(j,1),data(j,2),'s','MarkerSize',app.cubo,'MarkerFaceColor',c)
        else
            plot(app.a,data(j,1),data(j,2),'sk','MarkerSize',app.cubo,'MarkerFaceColor','k')
        end
        xlim(app.a,[0 size])
        ylim(app.a,[0 size])
        xticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
        xticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
        yticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
        yticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
        title(app.a,'Lebenspiel 0')
        hold (app.a,'on')
    end
end
if app.p == 1
    pause(30)
else
    pause(app.inter)
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Reglas del juego:
% 1: Una célula muerta con exáctamente 3 células vecinas vivas, volverá a
% la vida
% 2: Una célula viva con 2 o 3 vecinas vivas, continuará con vida
% 3: Una cáula viva que no cumpla con lo establecido en la regla 2, morirá
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

if app.n ~= 0
    for i = 1:app.n
        hold (app.a,'off')
        if app.p == 1
            px = 30;
        else 
            px = app.inter;
        end
        for y = 2:size-1
            for x = 2:size-1
                s = m1(y-1,x-1)+m1(y-1,x)+m1(y-1,x+1)+m1(y,x-1)+m1(y,x+1)+m1(y+1,x-1)+m1(y+1,x)+m1(y+1,x+1);
                if and(or(s == 3,s == 2),m1(y,x) == 1)
                    m2(y,x) = 1;
                elseif m1(y,x) == 1
                    m2(y,x) = 0;
                elseif and(s == 3,m1(y,x) == 0)
                    m2(y,x) = 1;
                end
                if m2(y,x) == 1
                    if app.zei == 1
                        c = rand;
                        c = round(20*c);
                        c = color(c);
                        plot(app.a,x,y,'s','MarkerSize',app.cubo,'MarkerFaceColor',c)
                    else
                        plot(app.a,x,y,'sk','MarkerSize',app.cubo,'MarkerFaceColor','k')
                    end
                    xlim(app.a,[0 size])
                    ylim(app.a,[0 size])
                    xticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
                    xticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
                    yticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
                    yticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
                    title(app.a,sprintf('Lebenspiel %d',i));
                    hold (app.a,'on')
                end
            end
        end
        if m2 == dummy
            plot(app.a,0,0,'ow')
            xlim(app.a,[0 size])
            ylim(app.a,[0 size])
            xticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
            xticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
            yticks(app.a,[0 size/5 2*size/5 3*size/5 4*size/5 size])
            yticklabels(app.a,{'0',sprintf('%d',size/5),sprintf('%d',2*size/5),sprintf('%d',3*size/5),sprintf('%d',4*size/5),sprintf('%d',size)})
            title(app.a,sprintf('Lebenspiel %d',i));
            break
        end
        m1 = m2;
        pause(px)
        m2 = zeros(size);
    end
end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.n = 0;
            app.file = '';
            app.start = 0;
            app.ejes = 100;
            app.inter = 0.5;
            app.cubo = 5;
            app.p = 0;
            app.zei = 0;
            app.r = 0;
            app.sem = 50;
        end

        % Value changed function: IteracionesEditField
        function IteracionesEditFieldValueChanged(app, event)
            app.n = app.IteracionesEditField.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file = app.FileNameEditField.Value;
        end

        % Value changed function: EjesEditField
        function EjesEditFieldValueChanged(app, event)
            app.ejes = app.EjesEditField.Value;
        end

        % Value changed function: IntervaloEditField
        function IntervaloEditFieldValueChanged(app, event)
            app.inter = app.IntervaloEditField.Value;
        end

        % Value changed function: TamaoCuboEditField
        function TamaoCuboEditFieldValueChanged(app, event)
            app.cubo = app.TamaoCuboEditField.Value;
        end

        % Value changed function: ColorCheckBox
        function ColorCheckBoxValueChanged(app, event)
            app.zei = app.ColorCheckBox.Value;
        end

        % Value changed function: PauseCheckBox
        function PauseCheckBoxValueChanged(app, event)
            app.p = app.PauseCheckBox.Value;
        end

        % Value changed function: RandomCheckBox
        function RandomCheckBoxValueChanged(app, event)
            app.r = app.RandomCheckBox.Value;
        end

        % Value changed function: SemillaEditField
        function SemillaEditFieldValueChanged(app, event)
            app.sem = app.SemillaEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            spiel(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Lebenspiel')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.a.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.a.XTickLabel = {'0'; '100'};
            app.a.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.a.YTickLabel = {'0'; '100'};
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.IteracionesEditField.ValueChangedFcn = createCallbackFcn(app, @IteracionesEditFieldValueChanged, true);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.EjesEditFieldLabel = uilabel(app.UIFigure);
            app.EjesEditFieldLabel.Text = 'Ejes';
            app.EjesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EjesEditField.Limits = [10 Inf];
            app.EjesEditField.ValueChangedFcn = createCallbackFcn(app, @EjesEditFieldValueChanged, true);
            app.EjesEditField.Value = 100;
            app.IntervaloEditFieldLabel = uilabel(app.UIFigure);
            app.IntervaloEditFieldLabel.Text = 'Intervalo';
            app.IntervaloEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervaloEditField.Limits = [1e-05 Inf];
            app.IntervaloEditField.ValueChangedFcn = createCallbackFcn(app, @IntervaloEditFieldValueChanged, true);
            app.IntervaloEditField.Value = 0.5;
            app.TamaoCuboEditFieldLabel = uilabel(app.UIFigure);
            app.TamaoCuboEditFieldLabel.Text = 'Tamaño Cubo';
            app.TamaoCuboEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamaoCuboEditField.Limits = [1 Inf];
            app.TamaoCuboEditField.ValueChangedFcn = createCallbackFcn(app, @TamaoCuboEditFieldValueChanged, true);
            app.TamaoCuboEditField.Value = 5;
            app.ColorCheckBox = uicheckbox(app.UIFigure);
            app.ColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorCheckBoxValueChanged, true);
            app.ColorCheckBox.Text = 'Color';
            app.PauseCheckBox = uicheckbox(app.UIFigure);
            app.PauseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PauseCheckBoxValueChanged, true);
            app.PauseCheckBox.Text = 'Pause';
            app.RandomCheckBox = uicheckbox(app.UIFigure);
            app.RandomCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBoxValueChanged, true);
            app.RandomCheckBox.Text = 'Random';
            app.SemillaEditFieldLabel = uilabel(app.UIFigure);
            app.SemillaEditFieldLabel.Text = '% Semilla';
            app.SemillaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SemillaEditField.Limits = [0 100];
            app.SemillaEditField.ValueChangedFcn = createCallbackFcn(app, @SemillaEditFieldValueChanged, true);
            app.SemillaEditField.Value = 50;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lebenspiel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end