classdef tetris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartButton                  matlab.ui.control.Button
        LeftButton                   matlab.ui.control.StateButton
        RightButton                  matlab.ui.control.StateButton
        DownButton                   matlab.ui.control.StateButton
        CounterclockwiseButton       matlab.ui.control.StateButton
        ClockwiseButton              matlab.ui.control.StateButton
        TimeEditFieldLabel           matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
        AxisKnobLabel                matlab.ui.control.Label
        AxisKnob                     matlab.ui.control.DiscreteKnob
        CubesizeEditFieldLabel       matlab.ui.control.Label
        CubesizeEditField            matlab.ui.control.NumericEditField
        a                            matlab.ui.control.UIAxes
        DiegoSnchezGonzlez2020Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        start % Start button
        left % Left button (state)
        right % Right button (state)
        down % Down button (state)
        aclock % Counterclock button (state)
        clock % Clockwise button (state)
        ejes % Height of the interface
        time % Time between animations
        cube % Size of the cube
    end
    
    methods (Access = private)
        
        function [M] = ftetris (app)
            hold (app.a,'off')
            o = 1;
            t = app.time;
            h = app.ejes;
            C = zeros(3,100);
            M = zeros(h);
%             m = zeros(h); 
            while o ~= 6
                r = rand;
                fi = 0; ft = 0; fl = 0; fj = 0; fs = 0; fz = 0;
                if r <= 1/7
                    shape = importdata('i_shapes.txt'); fi = 1; shape = shape+h-10; min1 = min(shape(:,2));
                    shape1 = importdata('i1_shapes.txt'); shape1 = shape1+h-10; min2 = min(shape1(:,2));
                    shape2 = 0;
                    shape3 = 0;
                elseif and(r<=2/7,r>1/7)
                    shape = importdata('o_shapes.txt'); shape = shape+h-10; min1 = min(shape(:,2)); 
                    shape1 = 0;
                    shape2 = 0;
                    shape3 = 0;
                elseif and(r<=3/7,r>2/7)
                    shape = importdata('t_shapes.txt'); ft = 1; shape = shape+h-10; min1 = min(shape(:,2));
                    shape1 = importdata('t1_shapes.txt'); shape1 = shape1+h-10; min2 = min(shape1(:,2));
                    shape2 = importdata('t2_shapes.txt'); shape2 = shape2+h-10; min3 = min(shape2(:,2));
                    shape3 = importdata('t3_shapes.txt'); shape3 = shape3+h-10; min4 = min(shape3(:,2));
                elseif and(r<=4/7,r>3/7)
                    shape = importdata('l_shapes.txt'); fl = 1; shape = shape+h-10; min1 = min(shape(:,2));
                    shape1 = importdata('l1_shapes.txt'); shape1 = shape1+h-10; min2 = min(shape1(:,2));
                    shape2 = importdata('l2_shapes.txt'); shape2 = shape2+h-10; min3 = min(shape2(:,2));
                    shape3 = importdata('l3_shapes.txt'); shape3 = shape3+h-10; min4 = min(shape3(:,2));
                elseif and(r<=5/7,r>4/7)
                    shape = importdata('j_shapes.txt'); fj = 1; shape = shape+h-10; min1 = min(shape(:,2));
                    shape1 = importdata('j1_shapes.txt'); shape1 = shape1+h-10; min2 = min(shape1(:,2));
                    shape2 = importdata('j2_shapes.txt'); shape2 = shape2+h-10; min3 = min(shape2(:,2));
                    shape3 = importdata('j3_shapes.txt'); shape3 = shape3+h-10; min4 = min(shape3(:,2));
                elseif and(r<=6/7,r>5/7)
                    shape = importdata('s_shapes.txt'); fs = 1; shape = shape+h-10; min1 = min(shape(:,2));
                    shape1 = importdata('s1_shapes.txt'); shape1 = shape1+h-10; min2 = min(shape1(:,2));
                    shape2 = 0;
                    shape3 = 0;
                elseif and(r<=1,r>6/7)
                    shape = importdata('z_shapes.txt'); fz = 1; shape = shape+h-10; min1 = min(shape(:,2));
                    shape1 = importdata('z1_shapes.txt'); shape1 = shape1+h-10; min2 = min(shape1(:,2));
                    shape2 = 0;
                    shape3 = 0;
                end
                
                m1 = zeros(h); m2 = zeros(h); m3 = zeros(h); m4 = zeros(h); 
%                 shape = shape+h-10; shape1 = shape1+h-10; shape2 = shape2+h-10; shape3 = shape3+h-10;
                c = rand; p = rand;
                c = round(20*c); p = where(p,h);
                c = color(c); C(:,o) = c;
                
                for j = 1:length(shape(:,1))
                    m1(shape(j,2),shape(j,1)+p) = 1;
                    plot(app.a,shape(j,1)+p,shape(j,2),'s','MarkerSize',app.cube,'MarkerFaceColor',C(:,o))                    
                    xlim(app.a,[0 h])
                    ylim(app.a,[0 h])
                    xticks(app.a,[0 h/5 2*h/5 3*h/5 4*h/5 h])
                    xticklabels(app.a,{'0',sprintf('%d',h/5),sprintf('%d',2*h/5),sprintf('%d',3*h/5),sprintf('%d',4*h/5),sprintf('%d',h)})
                    yticks(app.a,[0 h/5 2*h/5 3*h/5 4*h/5 h])
                    yticklabels(app.a,{'0',sprintf('%d',h/5),sprintf('%d',2*h/5),sprintf('%d',3*h/5),sprintf('%d',4*h/5),sprintf('%d',h)})
                    title(app.a,'Tetris')
                    hold (app.a,'on')
                    if or(or(fi == 1,fs == 1),fz == 1)
                        m2(shape1(j,2),shape1(j,1)+p) = 1;
                    elseif or(or(fl == 1,fj == 1),ft == 1)
                        m2(shape1(j,2),shape1(j,1)+p) = 1;
                        m3(shape2(j,2),shape2(j,1)+p) = 1;
                        m4(shape3(j,2),shape3(j,1)+p) = 1;
                    end
                    if and(o>1,j==1)
                        for w = 1:o-1
                            for y = 1:h
                                for x = 1:h
                                    if M(y,x) == w
                                        plot(app.a,x,y-1+0.5,'s','MarkerSize',app.cube,'MarkerFaceColor',C(:,w))
                                        hold (app.a,'on')
                                    end
                                end
                            end 
                        end
                    end
                end
                mc = m1; 
                pause(t)
                i = 1; r_ = 0; l_ = 0; k = 0; ak = 0; fin = 0; minx = min1;
                while i <= h
                    hold (app.a,'off')
                    if and(and(app.right == 1,app.left == 0),app.down == 0)
                        r_ = r_+1;
                    elseif and(and(app.right == 0,app.left == 1),app.down == 0)
                        l_ = l_-1;
                    elseif and(and(app.right == 0,app.left == 0),app.down == 1)
                        t = 0.01;
                    end
                    if and(app.clock == 1,app.aclock == 0)
                        if k >= 4
                            k = 0;
                        end
                        k = k+1;
                        add = abs(k + ak);
                        if or(or(fi == 1,fs == 1),fz == 1)
                            if or(add == 1,add == 3)
                                m1 = m2; minx = min2;
                            elseif or(add == 2,add == 4)
                                m1 = mc; minx = min1;
                            end
                        elseif or(or(ft == 1,fl == 1),fj == 1)
                            if add == 1
                                m1 = m2; minx = min2;
                            elseif add == 2
                                m1 = m3; minx = min3;
                            elseif add == 3
                                m1 = m4; minx = min4;
                            elseif add == 4
                                m1 = mc; minx = min1;
                            end
                        end
                    elseif and(app.clock == 0,app.aclock == 1)
                        if ak <= -4
                            ak = 0;
                        end
                        ak = ak-1;
                        add = abs(k + ak); 
                        if or(or(fi == 1,fs == 1),fz == 1)
                            if or(add == 1,add == 3)
                                m1 = m2; minx = min2;
                            elseif or(add == 2,add == 4)
                                m1 = mc; minx = min1;
                            end
                        elseif or(or(ft == 1,fl == 1),fj == 1)
                            if add == 1
                                m1 = m4; minx = min4;
                            elseif add == 2
                                m1 = m3; minx = min3;
                            elseif add == 3
                                m1 = m2; minx = min2;
                            elseif add == 4
                                m1 = mc; minx = min1;
                            end
                        end
                    end
                    for y = 1:h
                        for x = 1:h
                            if and(and(m1(y,x) == 1, y-i ~= -1),M(y-i+1,x+r_+l_) == 0)
                                plot(app.a,x+r_+l_,y-i+0.5,'s','MarkerSize',app.cube,'MarkerFaceColor',C(:,o))
                                hold (app.a,'on')
                            end
                            if y-i == 0
                                M(y-i+1,x+r_+l_) = o;
                                fin = 1;
%                             elseif y-i-1>0
                            elseif and(y-i ~= 0, M(y-i,x+r_+l_)~= 0)
%                                 if M(y-i-1,x+r_+l_) ~= 0
                                    M(y-i+1,x+r_+l_) = o;
                                    fin = 1;
%                                 end
                            elseif fin == 1
%                                 elseif and(y-i > 0, fin == 1)
                                M(y-i+1,x+r_+l_) = o;
                            end
                            if o>1                               
                                for w = 1:o-1
                                    if M(y,x) == w
                                        plot(app.a,x,y-1+0.5,'s','MarkerSize',app.cube,'MarkerFaceColor',C(:,w))
                                        hold (app.a,'on')
                                    end
                                end
                            end
                            if y-i-1>0
                                if and(m1(y,x) == 1, y-i == -1) || and(m1(y,x) == 1, M(y-i-1,x+r_+l_) ~= 0)
                                    break
                                end
                            end
                        end
                        if and(m1(y,x) == 1, y-i <= -1)
                            break
                        end
                    end
                    if and(m1(y,x) == 1, y-i <= -1)
                       break
                    end
                    i = i+1;
                    pause(t)
                end
%                 M = M + m;
                o = o + 1;
            end           
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 0;
            app.left = 0;
            app.right = 0;
            app.down = 0;
            app.aclock = 0;
            app.clock = 0;
            app.ejes = 10;
            app.time = 1;
            app.cube = 30;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            ftetris(app)
        end

        % Value changed function: LeftButton
        function LeftButtonValueChanged(app, event)
            app.left = app.LeftButton.Value;            
        end

        % Value changed function: RightButton
        function RightButtonValueChanged(app, event)
            app.right = app.RightButton.Value;
        end

        % Value changed function: DownButton
        function DownButtonValueChanged(app, event)
            app.down = app.DownButton.Value;
        end

        % Value changed function: CounterclockwiseButton
        function CounterclockwiseButtonValueChanged(app, event)
            app.aclock = app.CounterclockwiseButton.Value;
        end

        % Value changed function: ClockwiseButton
        function ClockwiseButtonValueChanged(app, event)
            app.clock = app.ClockwiseButton.Value;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.time = app.TimeEditField.Value;
        end

        % Value changed function: AxisKnob
        function AxisKnobValueChanged(app, event)
            app.ejes = str2double(app.AxisKnob.Value);
        end

        % Value changed function: CubesizeEditField
        function CubesizeEditFieldValueChanged(app, event)
            app.cube = app.CubesizeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LeftButton = uibutton(app.UIFigure, 'state');
            app.LeftButton.ValueChangedFcn = createCallbackFcn(app, @LeftButtonValueChanged, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'state');
            app.RightButton.ValueChangedFcn = createCallbackFcn(app, @RightButtonValueChanged, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.UIFigure, 'state');
            app.DownButton.ValueChangedFcn = createCallbackFcn(app, @DownButtonValueChanged, true);
            app.DownButton.Text = 'Down';
            app.CounterclockwiseButton = uibutton(app.UIFigure, 'state');
            app.CounterclockwiseButton.ValueChangedFcn = createCallbackFcn(app, @CounterclockwiseButtonValueChanged, true);
            app.CounterclockwiseButton.Text = 'Counterclockwise';
            app.ClockwiseButton = uibutton(app.UIFigure, 'state');
            app.ClockwiseButton.ValueChangedFcn = createCallbackFcn(app, @ClockwiseButtonValueChanged, true);
            app.ClockwiseButton.Text = 'Clockwise';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Limits = [0.1 Inf];
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 1;
            app.AxisKnobLabel = uilabel(app.UIFigure);
            app.AxisKnobLabel.Text = 'Axis';
            app.AxisKnob = uiknob(app.UIFigure, 'discrete');
            app.AxisKnob.Items = {'10', '20', '30', '40', '50'};
            app.AxisKnob.ValueChangedFcn = createCallbackFcn(app, @AxisKnobValueChanged, true);
            app.AxisKnob.Value = '10';
            app.CubesizeEditFieldLabel = uilabel(app.UIFigure);
            app.CubesizeEditFieldLabel.Text = 'Cube size';
            app.CubesizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CubesizeEditField.Limits = [1 Inf];
            app.CubesizeEditField.ValueChangedFcn = createCallbackFcn(app, @CubesizeEditFieldValueChanged, true);
            app.CubesizeEditField.Value = 30;
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Tetris')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DiegoSnchezGonzlez2020Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2020Label.Text = 'Diego Sánchez González 2020';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tetris

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end