classdef aproxpi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        a                            matlab.ui.control.UIAxes
        DotsEditFieldLabel           matlab.ui.control.Label
        DotsEditField                matlab.ui.control.NumericEditField
        AnfangButton                 matlab.ui.control.Button
        NummerEditFieldLabel         matlab.ui.control.Label
        NummerEditField              matlab.ui.control.NumericEditField
        EmpfehlungenKnobLabel        matlab.ui.control.Label
        EmpfehlungenKnob             matlab.ui.control.DiscreteKnob
        UsarvalorNummerCheckBox      matlab.ui.control.CheckBox
        UsarvalorempfehlungCheckBox  matlab.ui.control.CheckBox
        DiegoSnchezGonzlez2020Label  matlab.ui.control.Label
        Pi2Euler3Phi4RandomLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        dot % Number of dots
        start % start button
        num % Numero que se quiere aprox
        emp % Recomendaciones de numeros a aprox
        xnum % usar valor num
        xemp % usar valor emp
    end
    
    methods (Access = private)
        
        function calpi(app)
            
            hold (app.a,'off')
            k = 0;
            if app.xemp == 1
                if app.emp == 1
                   k = pi;
                elseif app.emp == 2
                   k = exp(1);
                elseif app.emp == 3
                   k = (1+sqrt(5))/2;
                elseif app.emp == 4
                   r = rand;
                   if r < 0.5
                       k = rand*100; 
                   else 
                       k = rand*10;
                   end
                end
            elseif app.xnum == 1
                k = app.num;
            end
            area = 0;
            dom1 = -1:0.001:1;
            r = sqrt(1/(k*pi));
            plot(app.a,dom1,0.5+sqrt(r^2-(dom1-0.5).^2),'k',dom1,0.5-sqrt(r^2-(dom1-0.5).^2),'k')
            xlim(app.a,[0 1]); ylim(app.a,[0 1])
            grid(app.a,'on')
            hold (app.a,'on')
            for i = 1:app.dot
                x = rand; y = rand;
                if (x-0.5)^2 + (y-0.5)^2 < r^2
                   area = area + 1; 
                   plot(app.a,x,y,'or')
                   title(app.a,sprintf('%d se aproxima a %d\ndots = %d',k,i/area,i))
                else
                   plot(app.a,x,y,'ob')
                   title(app.a,sprintf('%d se aproxima a %d\ndots = %d',k,i/area,i))
                end
                pause(0.000001)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dot = 100;
            app.start = 0;
            app.num = 1;
            app.emp = 1;
            app.xnum = 0;
            app.xemp = 0;
        end

        % Value changed function: DotsEditField
        function DotsEditFieldValueChanged(app, event)
            app.dot = app.DotsEditField.Value;
        end

        % Button pushed function: AnfangButton
        function AnfangButtonPushed(app, event)
            calpi (app)
        end

        % Value changed function: NummerEditField
        function NummerEditFieldValueChanged(app, event)
            app.num = app.NummerEditField.Value;
        end

        % Value changed function: EmpfehlungenKnob
        function EmpfehlungenKnobValueChanged(app, event)
            app.emp = str2double(app.EmpfehlungenKnob.Value);
        end

        % Value changed function: UsarvalorNummerCheckBox
        function UsarvalorNummerCheckBoxValueChanged(app, event)
            app.xnum = app.UsarvalorNummerCheckBox.Value;
        end

        % Value changed function: UsarvalorempfehlungCheckBox
        function UsarvalorempfehlungCheckBoxValueChanged(app, event)
            app.xemp = app.UsarvalorempfehlungCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Wahrscheinlichsrechnung')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DotsEditFieldLabel = uilabel(app.UIFigure);
            app.DotsEditFieldLabel.Text = 'Dots';
            app.DotsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DotsEditField.Limits = [0 Inf];
            app.DotsEditField.ValueChangedFcn = createCallbackFcn(app, @DotsEditFieldValueChanged, true);
            app.DotsEditField.Value = 100;
            app.AnfangButton = uibutton(app.UIFigure, 'push');
            app.AnfangButton.ButtonPushedFcn = createCallbackFcn(app, @AnfangButtonPushed, true);
            app.AnfangButton.Text = 'Anfang';
            app.NummerEditFieldLabel = uilabel(app.UIFigure);
            app.NummerEditFieldLabel.Text = 'Nummer';
            app.NummerEditField = uieditfield(app.UIFigure, 'numeric');
            app.NummerEditField.Limits = [1 Inf];
            app.NummerEditField.ValueChangedFcn = createCallbackFcn(app, @NummerEditFieldValueChanged, true);
            app.NummerEditField.Value = 1;
            app.EmpfehlungenKnobLabel = uilabel(app.UIFigure);
            app.EmpfehlungenKnobLabel.Text = 'Empfehlungen';
            app.EmpfehlungenKnob = uiknob(app.UIFigure, 'discrete');
            app.EmpfehlungenKnob.Items = {'1', '2', '3', '4'};
            app.EmpfehlungenKnob.ValueChangedFcn = createCallbackFcn(app, @EmpfehlungenKnobValueChanged, true);
            app.EmpfehlungenKnob.Value = '1';
            app.UsarvalorNummerCheckBox = uicheckbox(app.UIFigure);
            app.UsarvalorNummerCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsarvalorNummerCheckBoxValueChanged, true);
            app.UsarvalorNummerCheckBox.Text = 'Usar valor Nummer';
            app.UsarvalorempfehlungCheckBox = uicheckbox(app.UIFigure);
            app.UsarvalorempfehlungCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsarvalorempfehlungCheckBoxValueChanged, true);
            app.UsarvalorempfehlungCheckBox.Text = 'Usar valor empfehlung';
            app.DiegoSnchezGonzlez2020Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2020Label.Text = 'Diego Sánchez González 2020';
            app.Pi2Euler3Phi4RandomLabel = uilabel(app.UIFigure);
            app.Pi2Euler3Phi4RandomLabel.Text = {'1: Pi'; '2: Euler'; '3: Phi'; '4: Random'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aproxpi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end