classdef FourierT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NewdatasizeEditField         matlab.ui.control.NumericEditField
        NewdatasizeEditFieldLabel    matlab.ui.control.Label
        InterpolationCheckBox        matlab.ui.control.CheckBox
        TimeStepSizeEditField        matlab.ui.control.NumericEditField
        TimeStepSizeEditFieldLabel   matlab.ui.control.Label
        PlotpolarCheckBox            matlab.ui.control.CheckBox
        FrequencyStepSizeEditField   matlab.ui.control.NumericEditField
        FrequencyStepSizeEditFieldLabel  matlab.ui.control.Label
        TimeEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel           matlab.ui.control.Label
        InverseCheckBox              matlab.ui.control.CheckBox
        HighEditField                matlab.ui.control.NumericEditField
        HighEditFieldLabel           matlab.ui.control.Label
        LowEditField                 matlab.ui.control.NumericEditField
        LowEditFieldLabel            matlab.ui.control.Label
        FrequencylimitsLabel         matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        WaintingInputLabel           matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        EnterdataCheckBox            matlab.ui.control.CheckBox
        fxEditField                  matlab.ui.control.EditField
        fxEditFieldLabel             matlab.ui.control.Label
        EnterfunctionCheckBox        matlab.ui.control.CheckBox
        RandomCheckBox               matlab.ui.control.CheckBox
        StopCheckBox                 matlab.ui.control.CheckBox
        StartButton                  matlab.ui.control.Button
        FourierTransformLabel        matlab.ui.control.Label
        DiegoSnchezGonzlez2021Label  matlab.ui.control.Label
        c                            matlab.ui.control.UIAxes
        b                            matlab.ui.control.UIAxes
        a                            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        start % To start the program
        stop % To stop the program
        ran % to randomnize inputs
        isfun % to enter a function
        fun % to write the function
        isdata % to enter discret data
        file % to write the name of the file
        low % to specify low frequency boundary
        high % to specify high frequncy boundary
        inverse % to aply the inverse fourier
        time % To specify total time when data
        ffs % Frequency step size
        ppolar % To indicate if needed to plot polar
        tss % Time step size for specified functions
        inte % If linear interpolation needed
        nds % New data size for interpolation
    end
    
    methods (Access = private)
        
        function [M] = unmixer(app)
            if app.stop == 0
                % Initiate the interface
                app.Lamp.Color = 'r';
                app.WaintingInputLabel.Text = 'Still working';
                pause(0.1)
                % Create the input matrix
                if app.ran == 1
                    r = round(rand*10)+1;
                    r_ = round(rand(r,1).*10,1);
                    f = '@(x)';
                    for k = 1:r
                        r1 = gato10(5);
                        f = strcat(f,num2str(r1),'*sin(',num2str(r_(k)),'*x*2*pi)+');
                    end
                    f = f(1:end-1);
                    s = f;
                    s = s(5:end);
                    s = strcat('f(x)=',s);
                    f = str2func(f);
                elseif app.isfun == 1 % Isfun should be in the domain of x
                    f = str2func(strcat('@(x)',app.fun));
                    s = app.fun;
                    s = strcat('f(x)=',s);
                elseif app.isdata == 1
                    A = importdata(app.file);
                    if size(A,2) == 1
                        A = [linspace(0,app.time,size(A,1))' A];
                    end
                    if app.inte == 1
                        x = A(:,1);
                        v = A(:,2);
                        xq = x(1):(x(end)-x(1))/app.nds:x(end);
                        vq1 = interp1(x,v,xq);
                        A = [xq' vq1'];
                    end
                end
                if app.isdata == 0
                    title(app.a,sprintf('%s',s))
                    A = [];
                    for t = 0:app.tss:app.time
                        A = cat(1,A,[t f(t)]);
                    end
                end
                % Adjust limits
                if app.inverse == 1
                    l = 2;
                else
                    l = 1;
                end
                for k = 1:l
                    if k == 2
                        title(app.a,'Inverse')
                        hold (app.c,'on')
                    else
                        hold(app.c,'off');
                        plot(app.c,1,1);
                    end
                    hold(app.a,'off'); hold(app.b,'off');
                    plot(app.a,1,1); plot(app.b,1,1);
                    %                 hold(app.c,'off'); plot(app.c,1,1); hold(app.c,'on'); % mantener?
                    hold(app.a,'on'); hold(app.b,'on');
                    xlim(app.a,[min(A(:,1)) max(A(:,1))])
                    ylim(app.a,[min(A(:,2)) max(A(:,2))])
                    plot(app.a,A(:,1),A(:,2),'k')
                    pause(0.1)
                    M = [];
                    % Plotea la flor por cada freq y crea la funcion mag/frec
                    if k == 1
                        for n = app.low:app.ffs:app.high % Girar estos dos for
                            if app.stop == 1
                                break
                            end
                            C = [];
                            E = [];
                            hold(app.b,'on')
                            title(app.b,sprintf('Frequency %d',n))
                            for t = 1:size(A,1)
                                C = cat(1,C,A(t,2)*exp(-2*pi*1i*n*A(t,1)));
                                E = cat(1,E,exp(-2*pi*1i*n*A(t,1)));
                            end
                            M = cat(1,M,dot(A(:,2),E)/size(A,1)); % Dividir entre los input?
                            % Guardar las frecuencias grandes
                            if app.ppolar == 1
                                plot(app.b,real(C),imag(C),'k');
                                if n < app.high
                                    pause(0.1)
                                    hold (app.b,'off')
                                    plot(app.b,1,1)
                                end
                            end
                        end
                    else
                        for n = tim(1):tim(2)-tim(1):tim(end) % Girar estos dos for
                            if app.stop == 1
                                break
                            end
                            C = [];
                            E = [];
                            hold(app.b,'on')
                            title(app.b,sprintf('Time %d',n))
                            for t = 1:size(A,1)
                                C = cat(1,C,A(t,2)*exp(2*pi*1i*n*A(t,1)));
                                E = cat(1,E,exp(2*pi*1i*n*A(t,1)));
                            end
                            M = cat(1,M,dot(A(:,2),E)/size(A,1)); % Dividir entre los input?
                            % Guardar las frecuencias grandes
                            if app.ppolar == 1
                                plot(app.b,real(C),imag(C),'k');
                                if n < app.high
                                    pause(0.1)
                                    hold (app.b,'off')
                                    plot(app.b,1,1)
                                end
                            end
                        end
                    end
                    if app.stop == 1
                        break
                    end
                    if and(app.inverse == 1,k==1)
                        fr = app.low:app.ffs:app.high;
                        ylim(app.c,[min(abs(M)) max(abs(M))])
                        plot(app.c,fr,2*abs(M),'r')
                        for kk = 5:-1:1
                            app.WaintingInputLabel.Text = sprintf('Inverse in: %d', kk);
                            pause(0.1)
                        end
                        app.WaintingInputLabel.Text = 'Still working';
%                         ylim(app.c,[min(A(:,2))/A(end,1) max(A(:,2))/A(end,1)])
%                         plot(app.c,A(:,1),A(:,2)/A(end,1),'k')
                        %ylim(app.c,[min(A(:,2)) max(A(:,2))]) to plot
                        %plot(app.c,A(:,1),A(:,2)/A(end,1),'k') the original in wave freq place
                        tim = A(:,1);
                        A = [fr' 2*abs(M(:))];
                        pause(0.1)
                    elseif and(app.inverse == 0,k==1)
                        fr = app.low:app.ffs:app.high;
                        %ylim(app.c,[min(abs(M))*A(end,1) max(abs(M))*A(end,1)])
                        ylim(app.c,[min(abs(M)) max(abs(M))])
                        %plot(app.c,fr,abs(M)*A(end,1),'r')
                        plot(app.c,fr,2*abs(M),'r')
                        pause(0.1)
                    else
                        fr = tim; % Necesitamos cambiar esto
                        ylim(app.c,[min(abs(M)) max(abs(M))])
                        plot(app.c,fr,2*abs(M),'r')
                        pause(0.1)
                    end
                end
            end
            app.Lamp.Color = 'g';
            app.WaintingInputLabel.Text = 'Program finished';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 1;
            app.stop = 0;
            app.ran = 0;
            app.isfun = 0;
            app.fun = '';
            app.isdata = 0;
            app.file = '';
            app.low = 1;
            app.high = 10;
            app.inverse = 0;
            app.time = 0;
            app.ffs = 1;
            app.ppolar = 0;
            app.tss = 0.001;
            app.inte = 0;
            app.nds = 100;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            unmixer(app)
        end

        % Value changed function: StopCheckBox
        function StopCheckBoxValueChanged(app, event)
            app.stop = app.StopCheckBox.Value;
        end

        % Value changed function: RandomCheckBox
        function RandomCheckBoxValueChanged(app, event)
            app.ran = app.RandomCheckBox.Value;
        end

        % Value changed function: EnterfunctionCheckBox
        function EnterfunctionCheckBoxValueChanged(app, event)
            app.isfun = app.EnterfunctionCheckBox.Value;
        end

        % Value changed function: fxEditField
        function fxEditFieldValueChanged(app, event)
            app.fun = app.fxEditField.Value;
        end

        % Value changed function: EnterdataCheckBox
        function EnterdataCheckBoxValueChanged(app, event)
            app.isdata = app.EnterdataCheckBox.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file = app.FileNameEditField.Value;
        end

        % Value changed function: LowEditField
        function LowEditFieldValueChanged(app, event)
            app.low = app.LowEditField.Value;
        end

        % Value changed function: HighEditField
        function HighEditFieldValueChanged(app, event)
            app.high = app.HighEditField.Value;
        end

        % Value changed function: InverseCheckBox
        function InverseCheckBoxValueChanged(app, event)
            app.inverse = app.InverseCheckBox.Value;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.time = app.TimeEditField.Value;
        end

        % Value changed function: FrequencyStepSizeEditField
        function FrequencyStepSizeEditFieldValueChanged(app, event)
            app.ffs = app.FrequencyStepSizeEditField.Value;
        end

        % Value changed function: PlotpolarCheckBox
        function PlotpolarCheckBoxValueChanged(app, event)
            app.ppolar = app.PlotpolarCheckBox.Value;
        end

        % Value changed function: TimeStepSizeEditField
        function TimeStepSizeEditFieldValueChanged(app, event)
            app.tss = app.TimeStepSizeEditField.Value;
        end

        % Value changed function: InterpolationCheckBox
        function InterpolationCheckBoxValueChanged(app, event)
            app.inte = app.InterpolationCheckBox.Value;
        end

        % Value changed function: NewdatasizeEditField
        function NewdatasizeEditFieldValueChanged(app, event)
            app.nds = app.NewdatasizeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Original wave')
            xlabel(app.a, 'Time')
            ylabel(app.a, 'Intensity')
            app.b = uiaxes(app.UIFigure);
            title(app.b, 'Polar')
            xlabel(app.b, 'X')
            ylabel(app.b, 'Y')
            app.c = uiaxes(app.UIFigure);
            title(app.c, 'Spikes')
            xlabel(app.c, 'Frequency')
            ylabel(app.c, 'Magnitude')
            app.DiegoSnchezGonzlez2021Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2021Label.Text = 'Diego Sánchez González 2021';
            app.FourierTransformLabel = uilabel(app.UIFigure);
            app.FourierTransformLabel.Text = 'Fourier Transform';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopCheckBox = uicheckbox(app.UIFigure);
            app.StopCheckBox.ValueChangedFcn = createCallbackFcn(app, @StopCheckBoxValueChanged, true);
            app.StopCheckBox.Text = 'Stop';
            app.RandomCheckBox = uicheckbox(app.UIFigure);
            app.RandomCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBoxValueChanged, true);
            app.RandomCheckBox.Text = 'Random';
            app.EnterfunctionCheckBox = uicheckbox(app.UIFigure);
            app.EnterfunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnterfunctionCheckBoxValueChanged, true);
            app.EnterfunctionCheckBox.Text = 'Enter function';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f (x) =';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.ValueChangedFcn = createCallbackFcn(app, @fxEditFieldValueChanged, true);
            app.EnterdataCheckBox = uicheckbox(app.UIFigure);
            app.EnterdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnterdataCheckBoxValueChanged, true);
            app.EnterdataCheckBox.Text = 'Enter data';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.WaintingInputLabel = uilabel(app.UIFigure);
            app.WaintingInputLabel.Text = 'Wainting Input';
            app.Lamp = uilamp(app.UIFigure);
            app.FrequencylimitsLabel = uilabel(app.UIFigure);
            app.FrequencylimitsLabel.Text = 'Frequency limits';
            app.LowEditFieldLabel = uilabel(app.UIFigure);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowEditField.ValueChangedFcn = createCallbackFcn(app, @LowEditFieldValueChanged, true);
            app.LowEditField.Value = 1;
            app.HighEditFieldLabel = uilabel(app.UIFigure);
            app.HighEditFieldLabel.Text = 'High';
            app.HighEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighEditField.ValueChangedFcn = createCallbackFcn(app, @HighEditFieldValueChanged, true);
            app.HighEditField.Value = 10;
            app.InverseCheckBox = uicheckbox(app.UIFigure);
            app.InverseCheckBox.ValueChangedFcn = createCallbackFcn(app, @InverseCheckBoxValueChanged, true);
            app.InverseCheckBox.Text = 'Inverse';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.FrequencyStepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyStepSizeEditFieldLabel.Text = 'Frequency Step Size';
            app.FrequencyStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyStepSizeEditField.Limits = [1e-05 Inf];
            app.FrequencyStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyStepSizeEditFieldValueChanged, true);
            app.FrequencyStepSizeEditField.Value = 1;
            app.PlotpolarCheckBox = uicheckbox(app.UIFigure);
            app.PlotpolarCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotpolarCheckBoxValueChanged, true);
            app.PlotpolarCheckBox.Text = 'Plot polar';
            app.TimeStepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepSizeEditFieldLabel.Text = 'Time Step Size';
            app.TimeStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepSizeEditField.Limits = [1e-10 Inf];
            app.TimeStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStepSizeEditFieldValueChanged, true);
            app.TimeStepSizeEditField.Value = 0.001;
            app.InterpolationCheckBox = uicheckbox(app.UIFigure);
            app.InterpolationCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterpolationCheckBoxValueChanged, true);
            app.InterpolationCheckBox.Text = 'Interpolation';
            app.NewdatasizeEditFieldLabel = uilabel(app.UIFigure);
            app.NewdatasizeEditFieldLabel.Text = 'New data size';
            app.NewdatasizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewdatasizeEditField.Limits = [0 Inf];
            app.NewdatasizeEditField.ValueChangedFcn = createCallbackFcn(app, @NewdatasizeEditFieldValueChanged, true);
            app.NewdatasizeEditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end