classdef geometer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        a                            matlab.ui.control.UIAxes
        DiegoSnchezGonzlez2021Label  matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        IterationsSliderLabel        matlab.ui.control.Label
        IterationsSlider             matlab.ui.control.Slider
        ColorCheckBox                matlab.ui.control.CheckBox
        EachcircleCheckBox           matlab.ui.control.CheckBox
        SinglecircleCheckBox         matlab.ui.control.CheckBox
        NcircleEditFieldLabel        matlab.ui.control.Label
        NcircleEditField             matlab.ui.control.NumericEditField
        PolygonCheckBox              matlab.ui.control.CheckBox
        CirclesCheckBox              matlab.ui.control.CheckBox
        INCheckBox                   matlab.ui.control.CheckBox
        OUTCheckBox                  matlab.ui.control.CheckBox
        HintereinanderCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        start % button to start the programm
        iter % slider to give the number of iterations
        col % color of circles
        each % to focus on all circles
        single % to focus on a circle
        num % to give the n-circle
        poly % construct the polygon around the circle
        circ % Draw only circles
        in % Draw inwards
        out % draw outwards
        hinter % Button to evolve
    end
    
    methods (Access = private)
        
        function fgeometer(app)
            hold(app.a,'off')
            plot(app.a,100,100,'o')
            pass = true; r = 1; n = 1; nc = 1;
            z = round(app.iter); 
            if or(or(and(app.single == 1,app.each == 1),and(app.single==0,app.each==0)),or(and(app.in==1,app.out==1),and(app.in==0,app.out==0)))
                pass = false;
            end   
            if app.col == 0
                c = [0,0,0];
            end
            while and(n <= z, pass == true)
                    if app.hinter == 1
                        hold(app.a,'off')
                        plot(app.a,100,100,'o')
                    end
                    hold(app.a,'on')
                    v1 = []; again = 1; even = false;
                    if n ~= 2
                        syms x
                        eqn = -cot(2*pi/(n+2))*(x-r*cos(2*pi/(n+2)))+r*sin(2*pi/(n+2))==-cot(4*pi/(n+2))*(x-r*cos(4*pi/(n+2)))+r*sin(4*pi/(n+2));
                        A = double(solve(eqn,x));
                        B = -cot(2*pi/(n+2))*(A-r*cos(2*pi/(n+2)))+r*sin(2*pi/(n+2));
                        r2 = sqrt(A^2+B^2);
                    else
                        r2 = 2*sqrt(2);
                    end
                    if or(app.out == 1,n==1)
                        k = linspace(-r,r);
                        f = sqrt(r^2-k.^2);
                    end
                    if and(app.in == 1,n>1)
                        k = linspace(-1/r,1/r);
                        f = sqrt((1/r)^2-k.^2);
                    end
                    if app.col == 1
                        if nc <=20
                            c = color(nc);
                        else
                            nc = 1;
                            c = color(nc);
                        end
                        nc = nc+1;
                    end
                    if and(or(app.each == 1,and(app.single==1,app.num==n)),app.circ==1)
                        plot(app.a,k,f,'Color',c)
                        plot(app.a,k,-f,'Color',c)
                    end
                    if and(or(app.each == 1,and(app.single==1,app.num==n)),app.out==1)
                        title(app.a,sprintf('The Geometer R = %d\n N = %d',r,n))
                        xline(app.a,r,'Color',c)
                    end
                    if and(or(app.each == 1,and(app.single==1,app.num==n)),app.in==1)
                        title(app.a,sprintf('The Geometer R = %d\n N = %d',1/r2,n))
                        xline(app.a,1/r2,'Color',c)
                    end
                    if app.out==1
                        xlim(app.a,[-10 10]); ylim(app.a,[-10 10]); v1 = [r];
                    elseif app.in == 1
                        xlim(app.a,[-1 1]); ylim(app.a,[-1 1]); v1 = [1/r2];
                    end
                    for i = 1:n+1
                        if (2*i)/(n+2) >= 1
                            again = 0;
                        end
                        if and(and(n ~= 2, again == 1),app.out==1) 
                            syms x
                            eqn = -cot(2*pi*i/(n+2))*(x-r*cos(2*pi*i/(n+2)))+r*sin(2*pi*i/(n+2))==-cot((2*pi*i+2*pi)/(n+2))*(x-r*cos((2*pi*i+2*pi)/(n+2)))+r*sin((2*pi*i+2*pi)/(n+2));
                            A = double(solve(eqn,x));
                            v1(end+1) = A;
                        elseif and(and(n == 2, again == 1),app.out==1)
                            v1(end+1) = -2; again = 0;
                        elseif and(and(n ~= 2, again == 1),app.in==1) 
                            syms x
                            eqn = -cot(2*pi*i/(n+2))*(x-(1/r2)*cos(2*pi*i/(n+2)))+(1/r2)*sin(2*pi*i/(n+2))==-cot((2*pi*i+2*pi)/(n+2))*(x-(1/r2)*cos((2*pi*i+2*pi)/(n+2)))+(1/r2)*sin((2*pi*i+2*pi)/(n+2));
                            A = double(solve(eqn,x));
                            v1(end+1) = A;
                        elseif and(and(n == 2, again == 1),app.in==1)
                            v1(end+1) = -1/r2; again = 0;
                        end
                    end
                    again = 1; l = length(v1);
                    if or(app.each == 1,and(app.single==1,app.num==n))
                        for i = 1:n+1
                            if (n+2)/2 ~= i
                                if app.poly == 1                                
                                    if i+1<=l
                                        d = linspace(v1(i+1),v1(i));
                                    else
                                        if and(even == true,again == 1)
                                            l = l+1; again = 0;
                                        end
                                        d = linspace(v1(end-(i-l)),v1(end-(i-l)-1));
                                    end
                                elseif app.out==1
                                    d = linspace(-10,10);
                                elseif app.in == 1
                                    d = linspace(-1,1);
                                end
                                if app.out == 1
                                    g = -cot(2*pi*i/(n+2))*(d-r*cos(2*pi*i/(n+2)))+r*sin(2*pi*i/(n+2));
                                    plot(app.a,d,g,'Color',c)
                                elseif app.in == 1
                                    g = -cot(2*pi*i/(n+2))*(d-(1/r2)*cos(2*pi*i/(n+2)))+(1/r2)*sin(2*pi*i/(n+2));
                                    plot(app.a,d,g,'Color',c)
                                end
                            else
                                if app.out==1
                                    xline(app.a,-r,'Color',c)
                                    even = true;
                                elseif app.in == 1
                                    xline(app.a,-1/r2,'Color',c)
                                    even = true;
                                end
                            end
                        end
                    end
                    r = r2;
                    n = n+1;
                    pause(1) 
            end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 0;
            app.iter = 1;
            app.col = 1;
            app.each = 1;
            app.single = 0;
            app.num = 1;
            app.poly = 1;
            app.circ = 1;
            app.in = 0;
            app.out = 1;
            app.hinter = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            fgeometer (app)
        end

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            app.iter = app.IterationsSlider.Value;
        end

        % Value changed function: ColorCheckBox
        function ColorCheckBoxValueChanged(app, event)
            app.col = app.ColorCheckBox.Value;
        end

        % Value changed function: EachcircleCheckBox
        function EachcircleCheckBoxValueChanged(app, event)
            app.each = app.EachcircleCheckBox.Value;
        end

        % Value changed function: SinglecircleCheckBox
        function SinglecircleCheckBoxValueChanged(app, event)
            app.single = app.SinglecircleCheckBox.Value;
        end

        % Value changed function: NcircleEditField
        function NcircleEditFieldValueChanged(app, event)
            app.num = app.NcircleEditField.Value;
        end

        % Value changed function: PolygonCheckBox
        function PolygonCheckBoxValueChanged(app, event)
            app.poly = app.PolygonCheckBox.Value;
        end

        % Value changed function: CirclesCheckBox
        function CirclesCheckBoxValueChanged(app, event)
            app.circ = app.CirclesCheckBox.Value;
        end

        % Value changed function: INCheckBox
        function INCheckBoxValueChanged(app, event)
            app.in = app.INCheckBox.Value;
        end

        % Value changed function: OUTCheckBox
        function OUTCheckBoxValueChanged(app, event)
            app.out = app.OUTCheckBox.Value;
        end

        % Value changed function: HintereinanderCheckBox
        function HintereinanderCheckBoxValueChanged(app, event)
            app.hinter = app.HintereinanderCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'The Geometer')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DiegoSnchezGonzlez2021Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2021Label.Text = 'Diego Sánchez González 2021';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.IterationsSliderLabel = uilabel(app.UIFigure);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.UIFigure);
            app.IterationsSlider.Limits = [1 100];
            app.IterationsSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.IterationsSlider.Value = 1;
            app.ColorCheckBox = uicheckbox(app.UIFigure);
            app.ColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorCheckBoxValueChanged, true);
            app.ColorCheckBox.Text = 'Color';
            app.ColorCheckBox.Value = true;
            app.EachcircleCheckBox = uicheckbox(app.UIFigure);
            app.EachcircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @EachcircleCheckBoxValueChanged, true);
            app.EachcircleCheckBox.Text = 'Each circle';
            app.EachcircleCheckBox.Value = true;
            app.SinglecircleCheckBox = uicheckbox(app.UIFigure);
            app.SinglecircleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SinglecircleCheckBoxValueChanged, true);
            app.SinglecircleCheckBox.Text = 'Single circle';
            app.NcircleEditFieldLabel = uilabel(app.UIFigure);
            app.NcircleEditFieldLabel.Text = 'N-circle';
            app.NcircleEditField = uieditfield(app.UIFigure, 'numeric');
            app.NcircleEditField.Limits = [1 Inf];
            app.NcircleEditField.ValueChangedFcn = createCallbackFcn(app, @NcircleEditFieldValueChanged, true);
            app.NcircleEditField.Value = 1;
            app.PolygonCheckBox = uicheckbox(app.UIFigure);
            app.PolygonCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolygonCheckBoxValueChanged, true);
            app.PolygonCheckBox.Text = 'Polygon';
            app.PolygonCheckBox.Value = true;
            app.CirclesCheckBox = uicheckbox(app.UIFigure);
            app.CirclesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CirclesCheckBoxValueChanged, true);
            app.CirclesCheckBox.Text = 'Circles';
            app.CirclesCheckBox.Value = true;
            app.INCheckBox = uicheckbox(app.UIFigure);
            app.INCheckBox.ValueChangedFcn = createCallbackFcn(app, @INCheckBoxValueChanged, true);
            app.INCheckBox.Text = 'IN';
            app.OUTCheckBox = uicheckbox(app.UIFigure);
            app.OUTCheckBox.ValueChangedFcn = createCallbackFcn(app, @OUTCheckBoxValueChanged, true);
            app.OUTCheckBox.Text = 'OUT';
            app.OUTCheckBox.Value = true;
            app.HintereinanderCheckBox = uicheckbox(app.UIFigure);
            app.HintereinanderCheckBox.ValueChangedFcn = createCallbackFcn(app, @HintereinanderCheckBoxValueChanged, true);
            app.HintereinanderCheckBox.Text = 'Hintereinander';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geometer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end