classdef Winding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NomapCheckBox                matlab.ui.control.CheckBox
        HorizontalCheckBox           matlab.ui.control.CheckBox
        VerticalCheckBox             matlab.ui.control.CheckBox
        AlternatingCheckBox          matlab.ui.control.CheckBox
        StopCheckBox                 matlab.ui.control.CheckBox
        WindingNumberGauge           matlab.ui.control.Gauge
        WindingNumberGaugeLabel      matlab.ui.control.Label
        IterationsKnob               matlab.ui.control.DiscreteKnob
        IterationsKnobLabel          matlab.ui.control.Label
        WaitingforinputLabel         matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        Image                        matlab.ui.control.Image
        Y2EditField                  matlab.ui.control.NumericEditField
        Y2EditFieldLabel             matlab.ui.control.Label
        Y1EditField                  matlab.ui.control.NumericEditField
        Y1EditFieldLabel             matlab.ui.control.Label
        X2EditField                  matlab.ui.control.NumericEditField
        X2EditFieldLabel             matlab.ui.control.Label
        X1EditField                  matlab.ui.control.NumericEditField
        X1EditFieldLabel             matlab.ui.control.Label
        AxisLabel                    matlab.ui.control.Label
        wxEditField                  matlab.ui.control.EditField
        wxEditFieldLabel             matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        ComplexfunctionCheckBox      matlab.ui.control.CheckBox
        gxyEditField                 matlab.ui.control.EditField
        gxyEditFieldLabel            matlab.ui.control.Label
        fxyEditField                 matlab.ui.control.EditField
        fxyEditFieldLabel            matlab.ui.control.Label
        RealfunctionsCheckBox        matlab.ui.control.CheckBox
        DiegoSnchezGonzlez2021Label  matlab.ui.control.Label
        a                            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        start % Button to start the program
        real_ % Button for real valued functions
        complex % Button for complex valued functions
        f_real % f(x,y) stringed and real function
        g_real % g(x,y) stringed and real function
        c_com % c(x) stringed and complex function
        x1 % Low x value (axis)
        x2 % High x value (axis)
        y1 % Low y value (axis)
        y2 % High y value (axis)
        n % Iterations / 2^n partitions
        stop % Button to stop the program
        alter % Button to alternate
        hori % Button to always divide horizontaly
        verti % Button to always divide verticaly
        nmap % Button to not plot the map
    end
    
    methods (Access = private)
        
        function [C3] = roots(app)
            hold (app.a,"off")
            error = 0;
            h = 0.05;
            plot(app.a,1,1)
            hold (app.a,'on')
            app.WindingNumberGauge.MajorTickLabels = {'0','pi/2','pi','3*pi/2','2*pi'};
            app.WindingNumberGauge.Value = 0;
            title(app.a,'Winding')
            if and(app.real_==1,app.complex==0)
                f = str2func(strcat('@(x,y)',app.f_real));
                g = str2func(strcat('@(x,y)',app.g_real));
            elseif and(app.real_==0,app.complex==1)
                w = str2func(strcat('@(x)',app.c_com));
            else
                error = 1;
            end
            if error == 0
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Programa que crea el mapa de color
                zf = [];
                C3 = [];
                zr = [];
                difX = abs(app.x1-app.x2);
                difY = abs(app.y1-app.y2);
                B = round(sqrt((2^app.n)/(difY/difX)));
                A = round((2^app.n)/B);
                m = zeros(A,B);
                coords = m;
                hy = difY/A;
                hx = difX/B;
                hm = sqrt(hy^2+hx^2);
                xlim(app.a,[app.x1 app.x2])
                ylim(app.a,[app.y1 app.y2])
                app.Lamp.Color = 'r';
                app.WaitingforinputLabel.Text = 'Still working...';
                pause(0.1)
                b = 0;
                switch app.n
                    case 1
                        z = 30;
                    case 2
                        z = 27.5;
                    case 3
                        z = 25;
                    case 4
                        z = 22.5;
                    case 5
                        z = 20;
                    case 6
                        z = 17.5;
                    case 7
                        z = 15;
                    case 8
                        z = 12.5;
                    case 9
                        z = 10;
                    case 10
                        z = 10;
                    case 11
                        z = 10;
                    case 12
                        z = 10;
                end
                for k = app.y2:-hy:app.y1
                    b = b+1;
                    v = 0;
                    for t = app.x1:hx:app.x2
                        v = v+1;
                        coords(b,v) = t+k*1i;
                        if app.nmap == 0
                            if and(app.real_==1,app.complex==0)
                                r = sqrt(f(t,k)^2+g(t,k)^2);
                                if f(t,k)<0
                                    m(b,v) = atan(g(t,k)/f(t,k))+pi;
                                elseif g(t,k)<0
                                    m(b,v) = atan(g(t,k)/f(t,k))+2*pi;
                                elseif g(t,k)>=0
                                    m(b,v) = atan(g(t,k)/f(t,k));
                                end
                                if r<h
                                    m(b,v) = 100;
                                elseif abs(f(t,k)-g(t,k))<h
                                    zr = cat(1,zr,[t k]);
                                end
                            elseif and(app.real_==0,app.complex==1)
                                m(b,v) = angle(w(t+k*1i));
                                r = abs(w(t+k*1i));
                                if r<h
                                    m(b,v) = 100;
                                end
                            end
                            colors = windcolor(m,b,v);
                            plot(app.a,t,k,'o','MarkerFaceColor',colors,'MarkerSize',z,'MarkerEdgeColor','none')
                        end
                    end
                end
                app.Lamp.Color = 'y';
                app.AxisLabel.Text = 'Processing completed';
                s = size(coords);
                title(app.a,sprintf('Winding Size %d x %d',s(1),s(2)))
                for i = 5:-1:0
                    app.WaitingforinputLabel.Text = sprintf('Finding zeros in %d',i);
                    pause(1)
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Programa que encuentra los ceros 
                if app.stop == 0
                    dop = 1;
                    M_ = m;
                    C_ = coords;
                    if app.nmap == 1
                        s = size(C_);
                        M_ = zeros(s(1),s(2));
                    end
                    isV = 0;
                    isH = 1;
                    app.Lamp.Color = 'r';
                    app.WaitingforinputLabel.Text = 'Still working...';
                    for i = 1:app.n-1 % Número de iteraciones
                        if app.stop == 1
                            break
                        end
                        if i > 1
                            M_ = M3;
                            C_ = C3;
                        end
                        M3 = [];
                        C3 = [];
                        Wn = zeros(dop,2);
                        title(app.a,sprintf('Winding Iteration %d',i+1));
                        s_ = size(M_);
                        if or(and(app.hori==0,app.verti==0),app.alter==1)
                            if isV == 1
                                isV = 0;
                                isH = 1;
                            elseif isH == 1
                                isV = 1;
                                isH = 0;
                            end
                        elseif app.hori == 1
                            isH = 1;
                            isV = 0;
                        elseif app.verti == 1
                            isV = 1;
                            isH = 0;
                        end
                        for t = 1:dop % Ciclo que evalua cada zona con algun zero dentro
                            if isV == 1
                                if mod(size(M_,2),2) == 1
                                    M1 = M_(:,1:round(s_(2)/2),t);
                                    M2 = M_(:,round(s_(2)/2):end,t);
                                    C1 = C_(:,1:round(s_(2)/2),t);
                                    C2 = C_(:,round(s_(2)/2):end,t);
                                else
                                    M1 = M_(:,1:round(s_(2)/2),t);
                                    M2 = M_(:,round(s_(2)/2)+1:end,t);
                                    C1 = C_(:,1:round(s_(2)/2),t);
                                    C2 = C_(:,round(s_(2)/2)+1:end,t);
                                end
                            elseif isH == 1
                                if mod(size(M_,1),2) == 1
                                    M1 = M_(1:round(s_(1)/2),:,t);
                                    M2 = M_(round(s_(1)/2):end,:,t);
                                    C1 = C_(1:round(s_(1)/2),:,t);
                                    C2 = C_(round(s_(1)/2):end,:,t);
                                else
                                    M1 = M_(1:round(s_(1)/2),:,t);
                                    M2 = M_(round(s_(1)/2)+1:end,:,t);
                                    C1 = C_(1:round(s_(1)/2),:,t);
                                    C2 = C_(round(s_(1)/2)+1:end,:,t);
                                end
                            end
                            for j = 1:2 % Figura 1 / Figura 2 % Ciclo evalua cada corte (2 veces)
                                app.WindingNumberGaugeLabel.Text = 'Winding Number 0';
%                                 app.Lamp.Color = 'r';
%                                 app.WaitingforinputLabel.Text = 'Still working...';
                                switch j
                                    case 1
                                        M = M1;
                                        C = C1;
                                    case 2
                                        M = M2;
                                        C = C2;
                                end
                                s = size(M);
                                theta = 0;
                                for l = 1:4 % Estudiar cada lado del perimetro
                                    switch l
                                        case 1 % Horizontal hacia la derecha
                                            V1 = 1;
                                            V2 = 1;
                                            V3 = s(2);
                                            V4 = 1;
                                            V5 = '>';
                                        case 2 % Vertical hacia abajo
                                            V1 = 1;
                                            V2 = 1;
                                            V3 = s(1);
                                            V4 = s(2);
                                            V5 = 'v';
                                        case 3 % Horizontal hacia la izquierda
                                            V1 = s(2);
                                            V2 = -1;
                                            V3 = 1;
                                            V4 = s(1);
                                            V5 = '<';
                                        case 4 % Vertical hacia arriba
                                            V1 = s(1);
                                            V2 = -1;
                                            V3 = 1;
                                            V4 = 1;
                                            V5 = '^';
                                    end
                                    first = 1;
                                    for k = V1:V2:V3
                                        if mod(l,2) == 1
                                            if app.nmap == 1
                                                if and(app.real_==1,app.complex==0)
                                                    r = sqrt(f(real(C(V4,k)),imag(C(V4,k)))^2+g(real(C(V4,k)),imag(C(V4,k)))^2);
                                                    if f(real(C(V4,k)),imag(C(V4,k)))<0
                                                        M(V4,k) = atan(g(real(C(V4,k)),imag(C(V4,k)))/f(real(C(V4,k)),imag(C(V4,k))))+pi;
                                                    elseif g(real(C(V4,k)),imag(C(V4,k)))<0
                                                        M(V4,k) = atan(g(real(C(V4,k)),imag(C(V4,k)))/f(real(C(V4,k)),imag(C(V4,k))))+2*pi;
                                                    elseif g(real(C(V4,k)),imag(C(V4,k)))>=0
                                                        M(V4,k) = atan(g(real(C(V4,k)),imag(C(V4,k)))/f(real(C(V4,k)),imag(C(V4,k))));
                                                    end
                                                    if r<h
                                                        M(V4,k) = 100;
                                                    elseif abs(f(real(C(V4,k)),imag(C(V4,k)))-g(real(C(V4,k)),imag(C(V4,k))))<h
                                                        zr = cat(1,zr,[real(C(V4,k)) imag(C(V4,k))]); 
                                                    end
                                                elseif and(app.real_==0,app.complex==1)
                                                    M(V4,k) = angle(w(real(C(V4,k))+imag(C(V4,k))*1i));
                                                    r = abs(w(real(C(V4,k))+imag(C(V4,k))*1i));
                                                    if r<h
                                                        M(V4,k) = 100;
                                                    end
                                                end
                                            end
                                            if first == 0
                                                if M(V4,k)~=100
                                                    if M(V4,k-V2)~=100
                                                        if abs(M(V4,k)-M(V4,k-V2))>=2*pi-0.5 % Diferencia ajustable del salto
                                                            if M(V4,k)<M(V4,k-V2)
                                                                M(V4,k-V2) = M(V4,k-V2) - 2*pi;
                                                            else
                                                                M(V4,k-V2) = M(V4,k-V2) + 2*pi;
                                                            end
                                                        end
                                                        theta = theta+(M(V4,k)-M(V4,k-V2));
                                                    else
                                                        zf = cat(1,zf,[real(C(V4,k-V2)) imag(C(V4,k-V2))]);
                                                    end
                                                end
                                            end
                                            first = 0;
                                            app.WindingNumberGauge.Value = M(V4,k);
                                            colors = windcolor(M,V4,k);
                                            plot(app.a,real(C(V4,k)),imag(C(V4,k)),'o','MarkerFaceColor',[1 1 1],'MarkerSize',z,'MarkerEdgeColor','none')
                                            plot(app.a,real(C(V4,k)),imag(C(V4,k)),V5,'MarkerFaceColor',[0 0 0],'MarkerSize',z-5,'MarkerEdgeColor','none')
                                            pause(0.1)
                                            plot(app.a,real(C(V4,k)),imag(C(V4,k)),'o','MarkerFaceColor',colors,'MarkerSize',z,'MarkerEdgeColor','none')
                                        else
                                            if app.nmap == 1
                                                if and(app.real_==1,app.complex==0)
                                                    r = sqrt(f(real(C(k,V4)),imag(C(k,V4)))^2+g(real(C(k,V4)),imag(C(k,V4)))^2);
                                                    if f(real(C(k,V4)),imag(C(k,V4)))<0
                                                        M(k,V4) = atan(g(real(C(k,V4)),imag(C(k,V4)))/f(real(C(k,V4)),imag(C(k,V4))))+pi;
                                                    elseif g(real(C(k,V4)),imag(C(k,V4)))<0
                                                        M(k,V4) = atan(g(real(C(k,V4)),imag(C(k,V4)))/f(real(C(k,V4)),imag(C(k,V4))))+2*pi;
                                                    elseif g(real(C(k,V4)),imag(C(k,V4)))>=0
                                                        M(k,V4) = atan(g(real(C(k,V4)),imag(C(k,V4)))/f(real(C(k,V4)),imag(C(k,V4))));
                                                    end
                                                    if r<h
                                                        M(k,V4) = 100;
                                                    elseif abs(f(real(C(k,V4)),imag(C(k,V4)))-g(real(C(k,V4)),imag(C(k,V4))))<h
                                                        zr = cat(1,zr,[real(C(k,V4)) imag(C(k,V4))]); 
                                                    end
                                                elseif and(app.real_==0,app.complex==1)
                                                    M(k,V4) = angle(w(real(C(k,V4))+imag(C(k,V4))*1i));
                                                    r = abs(w(real(C(k,V4))+imag(C(k,V4))*1i));
                                                    if r<h
                                                        M(k,V4) = 100;
                                                    end
                                                end
                                            end
                                            if first == 0
                                                if M(k,V4)~=100
                                                    if M(k-V2,V4)~=100
                                                        if abs(M(k,V4)-M(k-V2,V4))>=2*pi-0.5 % Diferencia ajustable del salto
                                                            if M(k,V4)<M(k-V2,V4)
                                                                M(k-V2,V4) = M(k-V2,V4) - 2*pi;
                                                            else
                                                                M(k-V2,V4) = M(k-V2,V4) + 2*pi;
                                                            end
                                                        end
                                                        theta = theta+(M(k,V4)-M(k-V2,V4));
                                                    else
                                                        zf = cat(1,zf,[real(C(k-V2,V4)) imag(C(k-V2,V4))]);
                                                    end
                                                end
                                            end
                                            first = 0;
                                            app.WindingNumberGauge.Value = M(k,V4);
                                            colors = windcolor(M,k,V4);
                                            plot(app.a,real(C(k,V4)),imag(C(k,V4)),'o','MarkerFaceColor',[1 1 1],'MarkerSize',z,'MarkerEdgeColor','none')
                                            plot(app.a,real(C(k,V4)),imag(C(k,V4)),V5,'MarkerFaceColor',[0 0 0],'MarkerSize',z-5,'MarkerEdgeColor','none')
                                            pause(0.1)
                                            plot(app.a,real(C(k,V4)),imag(C(k,V4)),'o','MarkerFaceColor',colors,'MarkerSize',z,'MarkerEdgeColor','none')
                                        end
                                        % Codigo que compara en cada pixel si
                                        % la suma es mayor
                                        if or(theta >= 2*pi-0.5, theta <=-2*pi+0.5) % ¿Ajuste para aumentar posibles zeros a pesar de la presición? (0.5)
                                            Wn(t,j) = Wn(t,j)+1;
                                            theta = 0;
                                            app.WindingNumberGaugeLabel.Text = sprintf('Winding Number = %d',Wn(t,j));
                                        end
                                    end
                                    % Codigo que compara una vez completado un
                                    % lado si la suma es mayor
                                    %                                 if or(theta >= 2*pi-hw, theta <=-2*pi+hw)
                                    %                                     Wn(j) = Wn(j)+1;
                                    %                                     theta = 0;
                                    %                                     app.WindingNumberGaugeLabel.Text = sprintf('Winding Number = %d',Wn(j));
                                    %                                 end
                                end
                                % Código que divide el angulo en la cantidad de
                                % winding numbers y lo reporta una vez
                                % completado el perimetro
                                %                             Wn(j) = mod(abs(theta),2*pi);
                                %                             app.WindingNumberGaugeLabel.Text = sprintf('Winding Number = %d',Wn(j));
                            end
                            % Código que presenta los winding de cada lado
                            % y detiene el programa 5 seg entre cada
                            % iteracion
%                             title(app.a,sprintf('Winding %d - %d',Wn(t,1),Wn(t,2)))
%                             for d = 5:-1:1
%                                 app.WaitingforinputLabel.Text = sprintf('Choose a side or wait %d',d);
%                                 app.Lamp.Color = 'y';
%                                 pause(1)
%                             end
                            % Código que realiza la elimicación o concatenación
                            % de matrices
                            if and(Wn(t,1)>0,Wn(t,2)>0) 
                                M3 = cat(3,M3,cat(3,M1,M2));
                                C3 = cat(3,C3,cat(3,C1,C2));
                            elseif and(Wn(t,1)>0,Wn(t,2)==0)
                                M3 = cat(3,M3,M1);
                                C3 = cat(3,C3,C1);
                            elseif and(Wn(t,2)>0,Wn(t,1)==0)
                                M3 = cat(3,M3,M2);
                                C3 = cat(3,C3,C2);
                                % Si ambas son cero no hay copia
                            end
                        end
                        dop = size(M3,3);
                        if isempty(M3)
                            break
                        end
                    end
                end
                % Código que plotea los resultados
                if or(or(~isempty(C3),~isempty(zf)),~isempty(zr))
                    s = size(zf);
                    zf_ = zeros(s(1),s(2));
                    zf = [zf zf_];
                    if ~isempty(C3)
                        for u = 1:size(C3,3)
                            x = (real(C3(1,end,u))+real(C3(1,1,u)))/2;
                            y = (imag(C3(1,1,u))+imag(C3(end,1,u)))/2;
                            dx = abs(real(C3(1,end,u))-x);
                            dy = abs(imag(C3(end,1,u))-y);
                            zf = cat(1,zf,[x y dx dy]);
                        end
                    end
                    if ~isempty(zf)
                        zf_ = SameCoord(zf(:,1:2),hm);
                        if ~isequal(zf_, zf(:,1:2))
                            zf1 = [];
                            for u = 1:size(zf_,1)
                                for v = 1:size(zf,1)
                                    if and(zf(v,1)==zf_(u,1),zf(v,2)==zf_(u,2))
                                        zf1 = cat(1,zf1,zf(v,3:4));
                                        break
                                    end
                                end
                            end
                            zf = cat(2,zf_,zf1);
                        end
                        for u = 1:size(zf,1)
                            if and(app.real_==1,app.complex == 0)
                                R = sqrt(f(zf(u,1),zf(u,2))^2+g(zf(u,1),zf(u,2))^2);
                            elseif and(app.real_==0,app.complex==1)
                                R = abs(w(zf(u,1)+zf(u,2)*1i));
                            end
                            text(app.a,zf(u,1),zf(u,2),sprintf('%s\nX = %d %s %d\nY = %d %s %d\nR = %d','\uparrow',zf(u,1),'\pm',zf(u,3),zf(u,2),'\pm',zf(u,4),R),'VerticalAlignment','top','FontSize',10);
                        end
                    end
                    if ~isempty(zr)
                        for u = 1:size(zr,1)
                            plot(app.a,zr(u,1),zr(u,2),'o','MarkerFaceColor',[0 0 0],'MarkerSize',z,'MarkerEdgeColor','none')
                        end
                    end
                end
                title(app.a,'Winding completed')
                app.WaitingforinputLabel.Text = 'Finished Program';
                app.Lamp.Color = 'g';
            else
                app.Lamp.Color = 'r';
                app.WaitingforinputLabel.Text = 'Error';
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 1;
            app.real_ = 0;
            app.complex = 0;
            app.f_real = '';
            app.g_real = '';
            app.c_com = '';
            app.x1 = -1;
            app.x2 = 1;
            app.y1 = -1;
            app.y2 = 1;
            app.n = 1;
            app.stop = 0;
            app.alter = 1;
            app.hori = 0;
            app.verti = 0;
            app.nmap = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            roots(app)
        end

        % Value changed function: RealfunctionsCheckBox
        function RealfunctionsCheckBoxValueChanged(app, event)
            app.real_ = app.RealfunctionsCheckBox.Value;
        end

        % Value changed function: fxyEditField
        function fxyEditFieldValueChanged(app, event)
            app.f_real = app.fxyEditField.Value;
        end

        % Value changed function: gxyEditField
        function gxyEditFieldValueChanged(app, event)
            app.g_real = app.gxyEditField.Value;
        end

        % Value changed function: ComplexfunctionCheckBox
        function ComplexfunctionCheckBoxValueChanged(app, event)
            app.complex = app.ComplexfunctionCheckBox.Value;
        end

        % Value changed function: wxEditField
        function wxEditFieldValueChanged(app, event)
            app.c_com = app.wxEditField.Value;
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            app.x1 = app.X1EditField.Value;
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            app.x2 = app.X2EditField.Value;
        end

        % Value changed function: Y1EditField
        function Y1EditFieldValueChanged(app, event)
            app.y1 = app.Y1EditField.Value;
        end

        % Value changed function: Y2EditField
        function Y2EditFieldValueChanged(app, event)
            app.y2 = app.Y2EditField.Value;
        end

        % Value changed function: IterationsKnob
        function IterationsKnobValueChanged(app, event)
            app.n = str2double(app.IterationsKnob.Value);
        end

        % Value changed function: StopCheckBox
        function StopCheckBoxValueChanged(app, event)
            app.stop = app.StopCheckBox.Value;
        end

        % Value changed function: AlternatingCheckBox
        function AlternatingCheckBoxValueChanged(app, event)
            app.alter = app.AlternatingCheckBox.Value;
        end

        % Value changed function: VerticalCheckBox
        function VerticalCheckBoxValueChanged(app, event)
            app.verti = app.VerticalCheckBox.Value;
        end

        % Value changed function: HorizontalCheckBox
        function HorizontalCheckBoxValueChanged(app, event)
            app.hori = app.HorizontalCheckBox.Value;
        end

        % Value changed function: NomapCheckBox
        function NomapCheckBoxValueChanged(app, event)
            app.nmap = app.NomapCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Winding')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.DiegoSnchezGonzlez2021Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2021Label.Text = 'Diego Sánchez González 2021';
            app.RealfunctionsCheckBox = uicheckbox(app.UIFigure);
            app.RealfunctionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealfunctionsCheckBoxValueChanged, true);
            app.RealfunctionsCheckBox.Text = 'Real functions';
            app.fxyEditFieldLabel = uilabel(app.UIFigure);
            app.fxyEditFieldLabel.Text = 'f(x, y) = ';
            app.fxyEditField = uieditfield(app.UIFigure, 'text');
            app.fxyEditField.ValueChangedFcn = createCallbackFcn(app, @fxyEditFieldValueChanged, true);
            app.gxyEditFieldLabel = uilabel(app.UIFigure);
            app.gxyEditFieldLabel.Text = 'g(x, y) =';
            app.gxyEditField = uieditfield(app.UIFigure, 'text');
            app.gxyEditField.ValueChangedFcn = createCallbackFcn(app, @gxyEditFieldValueChanged, true);
            app.ComplexfunctionCheckBox = uicheckbox(app.UIFigure);
            app.ComplexfunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComplexfunctionCheckBoxValueChanged, true);
            app.ComplexfunctionCheckBox.Text = 'Complex function';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.wxEditFieldLabel = uilabel(app.UIFigure);
            app.wxEditFieldLabel.Text = 'w(x) =';
            app.wxEditField = uieditfield(app.UIFigure, 'text');
            app.wxEditField.ValueChangedFcn = createCallbackFcn(app, @wxEditFieldValueChanged, true);
            app.AxisLabel = uilabel(app.UIFigure);
            app.AxisLabel.Text = 'Axis';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X1EditField.Value = -1;
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.X2EditField.Value = 1;
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @Y1EditFieldValueChanged, true);
            app.Y1EditField.Value = -1;
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @Y2EditFieldValueChanged, true);
            app.Y2EditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ColorWheel.jpg';
            app.Lamp = uilamp(app.UIFigure);
            app.WaitingforinputLabel = uilabel(app.UIFigure);
            app.WaitingforinputLabel.Text = 'Waiting for input';
            app.IterationsKnobLabel = uilabel(app.UIFigure);
            app.IterationsKnobLabel.Text = 'Iterations';
            app.IterationsKnob = uiknob(app.UIFigure, 'discrete');
            app.IterationsKnob.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.IterationsKnob.ItemsData = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.IterationsKnob.ValueChangedFcn = createCallbackFcn(app, @IterationsKnobValueChanged, true);
            app.IterationsKnob.Value = '2';
            app.WindingNumberGaugeLabel = uilabel(app.UIFigure);
            app.WindingNumberGaugeLabel.Text = 'Winding Number';
            app.WindingNumberGauge = uigauge(app.UIFigure, 'circular');
            app.WindingNumberGauge.Limits = [0 6.28318530717959];
            app.WindingNumberGauge.MajorTicks = [0 1.5707963267949 3.14159265358979 4.71238898038469 6.28318530717959];
            app.StopCheckBox = uicheckbox(app.UIFigure);
            app.StopCheckBox.ValueChangedFcn = createCallbackFcn(app, @StopCheckBoxValueChanged, true);
            app.StopCheckBox.Text = 'Stop';
            app.AlternatingCheckBox = uicheckbox(app.UIFigure);
            app.AlternatingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AlternatingCheckBoxValueChanged, true);
            app.AlternatingCheckBox.Text = 'Alternating';
            app.AlternatingCheckBox.Value = true;
            app.VerticalCheckBox = uicheckbox(app.UIFigure);
            app.VerticalCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerticalCheckBoxValueChanged, true);
            app.VerticalCheckBox.Text = 'Vertical';
            app.HorizontalCheckBox = uicheckbox(app.UIFigure);
            app.HorizontalCheckBox.ValueChangedFcn = createCallbackFcn(app, @HorizontalCheckBoxValueChanged, true);
            app.HorizontalCheckBox.Text = 'Horizontal';
            app.NomapCheckBox = uicheckbox(app.UIFigure);
            app.NomapCheckBox.ValueChangedFcn = createCallbackFcn(app, @NomapCheckBoxValueChanged, true);
            app.NomapCheckBox.Text = 'No map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Winding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end