classdef windmill < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DiegoSnchezGonzlez2021Label  matlab.ui.control.Label
        a                            matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.Button
        RandompointsCheckBox         matlab.ui.control.CheckBox
        RandomEditFieldLabel         matlab.ui.control.Label
        RandomEditField              matlab.ui.control.NumericEditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        SelectcoordsLabel            matlab.ui.control.Label
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        RandomcoordsCheckBox         matlab.ui.control.CheckBox
        ClockwiseCheckBox            matlab.ui.control.CheckBox
        DestroyCheckBox              matlab.ui.control.CheckBox
        Lamp                         matlab.ui.control.Lamp
        PassLabel                    matlab.ui.control.Label
        TimerLabel                   matlab.ui.control.Label
        Knob                         matlab.ui.control.DiscreteKnob
        SpeedLabel                   matlab.ui.control.Label
        MiddleCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        start % to start the windmill
        r_points % to create random points
        percent % to define the percentage of random points
        file % to call the data
        r_coords % to select a random coords
        x_coord % to select x coord
        y_coord % to select y coord
        clock % to rotate clockwise
        destroy % to destroy the figure
        vel % vel of windmill
        middle % Select middle point
    end
    
    methods (Access = private)
        
        function wind(app)
            again = true;
            enter = true;
            h = 0.01; % Incertidumbre del angulo (0.01) rad
            while again == true
                hold(app.a,"off")
                plot(app.a,1,1)
                again = false;
                % Código que importa o crea los datos
                if app.r_points == 0
                    data = importdata(app.file); 
                    data = data';
                elseif app.r_points == 1
                    data = rand(2,app.percent); 
                    data = data.*100;
                end
                s = size(data); 
                s = s(2);
                x = sort(data(1,:));
                copy = data;
                c = 0;
                % Código que ordena el array
                for i = 1:s
                    for j = 1:s-c
                        if x(i)==copy(1,j)
                            data(1,i) = x(i);
                            data(2,i) = copy(2,j);
                            copy(:,j) = [];
                            c = c+1;
                            break
                        end
                    end
                    if or(data(1,i)>100,data(2,i)>100)
                        again = true;
                    end
                end
                m = zeros(s*((s-1)/2),5);
                l = 1;
                % Se calculan las pendientes no triviales y se comparan
                for i = 1:s-1
                    k = l;
                    for j = i+1:s
                        m(l,1:4) = [data(1,i) data(2,i) data(1,j) data(2,j)];
                        m(l,5) = atan((data(2,i)-data(2,j))/(data(1,i)-data(1,j)));
                        l = l+1;
                    end
                    for b = k:l-2
                        for v = b+1:l-1
                            if and(m(b,5)-h<m(v,5),m(b,5)+h>m(v,5))
                                again = true;
                                break
                            end
                        end
                        if and(m(b,5)-h<m(v,5),m(b,5)+h>m(v,5))
                            again = true;
                            break
                        end
                    end
                    if and(m(b,5)-h<m(v,5),m(b,5)+h>m(v,5))
                        again = true;
                        break
                    end
                end
                % Se grafica independientemente del output
                xlim(app.a,[0 100])
                ylim(app.a,[0 100])
                xticks(app.a,[0 20 40 60 80 100])
                xticklabels(app.a,{'0','20','40','60','80','100'})
                yticks(app.a,[0 20 40 60 80 100])
                yticklabels(app.a,{'0','20','40','60','80','100'})
                title(app.a,'Windmill')
                hold (app.a,'on')
                for k = 1:s
                    plot(app.a, data(1,k), data(2,k),'ok','MarkerSize',10,'MarkerFaceColor','k')
                    text(app.a,data(1,k),data(2,k),sprintf('%s\n(%d,%d)','\uparrow',data(1,k),data(2,k)),'VerticalAlignment','top','FontSize',10);
                end
                % Se anuncia el resultado
                if again == false
                    app.PassLabel.Text = 'Pass';
                    app.Lamp.Color = 'g';
                    app.SelectcoordsLabel.Text = 'Select coords, click random or middle';
                    for u = 10:-1:0
                        app.TimerLabel.Text = sprintf('%d',u);
                        pause(1)
                    end
                elseif again == true
                    app.PassLabel.Text = 'No pass';
                    app.Lamp.Color = 'r';
                    if app.r_points == 1
                        app.SelectcoordsLabel.Text = 'Reprocessing...';
                        pause(0.1)
                    elseif app.r_points == 0
                        app.SelectcoordsLabel.Text = 'Verify data';
                        enter = false;
                        break
                    end
                end
            end
            data = [data;zeros(1,s)];
            colors = zeros(s,3);
            red = [1 0 0];
            blue = [0 0 1];
            black = [0 0 0];
            % Código que define el primer pivote
            while again == false             
                if app.middle == 1
                    r = round(s/2);
                    p = [data(1,r) data(2,r)];
                    data(3,r) = 1;
                    again = true;
                    app.SelectcoordsLabel.Text = 'Windmill on progress';
                    app.PassLabel.Text = 'Pass';
                    app.Lamp.Color = 'g';
                elseif app.r_coords == 1
                    r = gato10(s);
                    p = [data(1,r) data(2,r)];
                    data(3,r) = 1;
                    again = true;
                    app.SelectcoordsLabel.Text = 'Windmill on progress';
                    app.PassLabel.Text = 'Pass';
                    app.Lamp.Color = 'g';
                elseif app.r_coords == 0
                    if app.destroy == 1
                        break
                    end
                    again = true;
                    p = [];
                    for i = 1:s
                        if and(and(app.x_coord-0.1<data(1,i),app.x_coord+0.1>data(1,i)),and(app.y_coord-0.1<data(2,i),app.y_coord+0.1>data(2,i)))
                            p = [data(1,i) data(2,i)];
                            break
                        end
                    end
                    if isempty(p)
                        app.PassLabel.Text = 'No pass';
                        app.Lamp.Color = 'r';
                        app.SelectcoordsLabel.Text = 'Enter valid coords';
                        again = false;
                        for u = 10:-1:0
                            app.TimerLabel.Text = sprintf('%d',u);
                            pause(1)
                        end
                    else
                        app.SelectcoordsLabel.Text = 'Windmill on progress';
                        data(3,i) = 1;
                        app.PassLabel.Text = 'Pass';
                        app.Lamp.Color = 'g';
                    end
                end
            end
            % Código que ordena las pendientes de cada punto
            if enter == true
                M = zeros(s*(s-1),5);
                b = 1;
                for i = 1:s
                    for j = 1:s*(s-1)
                        if j<=s*((s-1)/2)
                            if and(data(1,i)==m(j,1),data(2,i)==m(j,2))
                                M(b,1:5) = m(j,1:5);
                                b = b+1;
                            end
                        else
                            k = j-s*((s-1)/2);
                            if and(data(1,i)==m(k,3),data(2,i)==m(k,4))
                                M(b,1:2) = m(k,3:4);
                                M(b,3:4) = m(k,1:2);
                                M(b,5) = m(k,5);
                                b = b+1;
                            end
                        end
                    end
                end
            end
            d = linspace(0,100); 
            angle = pi/2;
            f = 0;
            e = 1;
            z = 1;
            % Código que realiza la animación
            while and(again == true,enter==true)
                hold(app.a,"off")
                if app.destroy == 1
                    break
                end
                % Código que suma angulos 
                if app.clock == 1
                    if app.vel == 1
                        angle = angle-(pi/300);
                    elseif app.vel == 2
                        angle = angle-(pi/250);
                    elseif app.vel == 3
                        angle = angle-(pi/200);
                    end
                    if angle<-pi/2
                        angle = pi/2;
                        if f == 0
                            e = 0;
                        else
                            e = 1;
                        end
                    end
                else
                    if app.vel == 1
                        angle = angle+(pi/300);
                    elseif app.vel == 2
                        angle = angle+(pi/250);
                    elseif app.vel == 3
                        angle = angle+(pi/200);
                    end
                    if angle>pi/2
                        angle = -pi/2;
                        if f == 0
                            e = 0;
                        else
                            e = 1;
                        end
                    end
                end
                % Código que intercambia el pivote
                if z > 1
                    for i = (j-1)*(s-1)+1:j*(s-1)
                        if and(angle-h<M(i,5),angle+h>M(i,5)) 
                            z = 1;
                            p(1) = M(i,3);
                            p(2) = M(i,4);
                            for k = 1:s
                                if and(data(1,k)==M(i,3),data(2,k)==M(i,4))
                                    data(3,k) = data(3,k)+1;
                                end
                            end
                        end
                    end
                else
                    for j = 1:s
                        if and(data(1,j)==p(1),data(2,j)==p(2))
                            break
                        end
                    end
                z = z+1;
                end
                y = tan(angle)*(d-p(1))+p(2);
                c = 0; 
                b = 0;
                for k = 1:s
                    if and(data(2,k)>tan(angle)*(data(1,k)-p(1))+p(2),e==0)
                        colors(k,:) = blue;
                        c = c+1;
                        f = 1;
                    elseif and(data(2,k)<tan(angle)*(data(1,k)-p(1))+p(2),e==0)
                        colors(k,:) = red;
                        b = b+1;
                        f = 1;
                    elseif and(data(2,k)>tan(angle)*(data(1,k)-p(1))+p(2),e==1)
                        colors(k,:) = red;
                        b = b+1;
                        f = 0;
                    elseif and(data(2,k)<tan(angle)*(data(1,k)-p(1))+p(2),e==1)
                        colors(k,:) = blue;
                        c = c+1;
                        f = 0;
                    elseif data(2,k)==tan(angle)*(data(1,k)-p(1))+p(2)
                        if mod(s,2)==0
                            if e == 0
                                colors(k,:) = blue;
                                c = c+1;
                                f = 1;
                            else
                                colors(k,:) = red;
                                c = c+1;
                                f = 0;
                            end
                        else
                            colors(k,:) = black;
                        end
                    end
                    plot(app.a, data(1,k), data(2,k),'ok','MarkerSize',10,'MarkerFaceColor',colors(k,:))
                    text(app.a,data(1,k),data(2,k),sprintf('%s\n%d','\uparrow',data(3,k)),'VerticalAlignment','top','FontSize',10);
                    hold (app.a,'on')
                end
                plot(app.a,d,y,'k')
                title(app.a,sprintf('Windmill\n%d Blue - %d Red',c,b))
                pause(0.1)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = 1;
            app.r_points = 0;
            app.file = '';
            app.r_coords = 0;
            app.x_coord = 0;
            app.y_coord = 0;
            app.clock = 1;
            app.destroy = 0;
            app.vel = 1;
            app.middle = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)
            wind(app)
        end

        % Value changed function: RandompointsCheckBox
        function RandompointsCheckBoxValueChanged(app, event)
            app.r_points = app.RandompointsCheckBox.Value;
        end

        % Value changed function: RandomEditField
        function RandomEditFieldValueChanged(app, event)
            app.percent = app.RandomEditField.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file = app.FileNameEditField.Value;
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
           app.x_coord = app.xEditField.Value;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.y_coord = app.yEditField.Value;
        end

        % Value changed function: RandomcoordsCheckBox
        function RandomcoordsCheckBoxValueChanged(app, event)
            app.r_coords = app.RandomcoordsCheckBox.Value;
        end

        % Value changed function: ClockwiseCheckBox
        function ClockwiseCheckBoxValueChanged(app, event)
            app.clock = app.ClockwiseCheckBox.Value;
        end

        % Value changed function: DestroyCheckBox
        function DestroyCheckBoxValueChanged(app, event)
            app.destroy = app.DestroyCheckBox.Value;
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            app.vel = str2double(app.Knob.Value);
        end

        % Value changed function: MiddleCheckBox
        function MiddleCheckBoxValueChanged(app, event)
            app.middle = app.MiddleCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiegoSnchezGonzlez2021Label = uilabel(app.UIFigure);
            app.DiegoSnchezGonzlez2021Label.Text = 'Diego Sánchez González - 2021';
            app.a = uiaxes(app.UIFigure);
            title(app.a, 'Windmill')
            xlabel(app.a, 'X')
            ylabel(app.a, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = 'Start';
            app.RandompointsCheckBox = uicheckbox(app.UIFigure);
            app.RandompointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandompointsCheckBoxValueChanged, true);
            app.RandompointsCheckBox.Text = 'Random points';
            app.RandomEditFieldLabel = uilabel(app.UIFigure);
            app.RandomEditFieldLabel.Text = 'Random %';
            app.RandomEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomEditField.Limits = [0 100];
            app.RandomEditField.ValueChangedFcn = createCallbackFcn(app, @RandomEditFieldValueChanged, true);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.SelectcoordsLabel = uilabel(app.UIFigure);
            app.SelectcoordsLabel.Text = 'Select coords';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Limits = [0 100];
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Limits = [0 100];
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.RandomcoordsCheckBox = uicheckbox(app.UIFigure);
            app.RandomcoordsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomcoordsCheckBoxValueChanged, true);
            app.RandomcoordsCheckBox.Text = 'Random coords';
            app.ClockwiseCheckBox = uicheckbox(app.UIFigure);
            app.ClockwiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClockwiseCheckBoxValueChanged, true);
            app.ClockwiseCheckBox.Text = 'Clockwise';
            app.ClockwiseCheckBox.Value = true;
            app.DestroyCheckBox = uicheckbox(app.UIFigure);
            app.DestroyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DestroyCheckBoxValueChanged, true);
            app.DestroyCheckBox.Text = 'Destroy';
            app.Lamp = uilamp(app.UIFigure);
            app.PassLabel = uilabel(app.UIFigure);
            app.PassLabel.Text = 'Pass';
            app.TimerLabel = uilabel(app.UIFigure);
            app.TimerLabel.Text = 'Timer';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'1', '2', '3'};
            app.Knob.ItemsData = {'1', '2', '3'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = '1';
            app.SpeedLabel = uilabel(app.UIFigure);
            app.SpeedLabel.Text = 'Speed';
            app.MiddleCheckBox = uicheckbox(app.UIFigure);
            app.MiddleCheckBox.ValueChangedFcn = createCallbackFcn(app, @MiddleCheckBoxValueChanged, true);
            app.MiddleCheckBox.Text = 'Middle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = windmill

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end